classdef ADRIA_BBN_app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InterventionInferenceTab        matlab.ui.container.Tab
        PlotDropDown                    matlab.ui.control.DropDown
        PlotDropDownLabel               matlab.ui.control.Label
        MetricDropDown                  matlab.ui.control.DropDown
        MetricDropDownLabel             matlab.ui.control.Label
        RCPKnob                         matlab.ui.control.DiscreteKnob
        RCPKnobLabel                    matlab.ui.control.Label
        MCDAAlgorithmButtonGroup        matlab.ui.container.ButtonGroup
        VIKORButton                     matlab.ui.control.RadioButton
        TOPSISButton                    matlab.ui.control.RadioButton
        OrderRankingButton              matlab.ui.control.RadioButton
        SiteGroupButtonGroup            matlab.ui.container.ButtonGroup
        CButton                         matlab.ui.control.RadioButton
        BButton                         matlab.ui.control.RadioButton
        AButton                         matlab.ui.control.RadioButton
        InterventionSelectionPanel      matlab.ui.container.Panel
        MakeInterventionInferenceButton  matlab.ui.control.Button
        NatAdptDropDown                 matlab.ui.control.DropDown
        NatAdptDropDownLabel            matlab.ui.control.Label
        AsAdptDropDown                  matlab.ui.control.DropDown
        AsAdptDropDownLabel             matlab.ui.control.Label
        SRMDropDown                     matlab.ui.control.DropDown
        SRMDropDownLabel                matlab.ui.control.Label
        Seed2DropDown                   matlab.ui.control.DropDown
        Seed2DropDownLabel              matlab.ui.control.Label
        Seed1DropDown                   matlab.ui.control.DropDown
        Seed1DropDownLabel              matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MetricInferenceTab              matlab.ui.container.Tab
        InferenceresultsTextArea        matlab.ui.control.TextArea
        InterventionlevelsPanel         matlab.ui.container.Panel
        AsAdtGauge                      matlab.ui.control.LinearGauge
        NatAdtGauge                     matlab.ui.control.LinearGauge
        SRMGauge                        matlab.ui.control.LinearGauge
        Seed2Gauge                      matlab.ui.control.LinearGauge
        Seed1Gauge                      matlab.ui.control.LinearGauge
        AsAdtLamp                       matlab.ui.control.Lamp
        AsAdtLampLabel                  matlab.ui.control.Label
        NatAdtLamp                      matlab.ui.control.Lamp
        NatAdtLampLabel                 matlab.ui.control.Label
        SRMLamp                         matlab.ui.control.Lamp
        SRMLampLabel                    matlab.ui.control.Label
        Seed2Lamp                       matlab.ui.control.Lamp
        Seed2LampLabel                  matlab.ui.control.Label
        Seed1Lamp                       matlab.ui.control.Lamp
        Seed1LampLabel                  matlab.ui.control.Label
        NoninterventionparametersPanel  matlab.ui.container.Panel
        MCDAAlgorithmButtonGroup_2      matlab.ui.container.ButtonGroup
        VIKORButton_2                   matlab.ui.control.RadioButton
        TOPSISButton_2                  matlab.ui.control.RadioButton
        RankorderingButton              matlab.ui.control.RadioButton
        SiteSelectionButtonGroup        matlab.ui.container.ButtonGroup
        CButton_2                       matlab.ui.control.RadioButton
        BButton_2                       matlab.ui.control.RadioButton
        AButton_2                       matlab.ui.control.RadioButton
        RCPKnob_2                       matlab.ui.control.DiscreteKnob
        RCPKnob_2Label                  matlab.ui.control.Label
        InferenceparametersPanel        matlab.ui.container.Panel
        ProvisionalESButtonGroup        matlab.ui.container.ButtonGroup
        UnknownPES                      matlab.ui.control.RadioButton
        ProbabilityLTPES                matlab.ui.control.RadioButton
        ProbabilityGTPES                matlab.ui.control.RadioButton
        AveragePES                      matlab.ui.control.RadioButton
        CulturalESButtonGroup           matlab.ui.container.ButtonGroup
        UnknownCES                      matlab.ui.control.RadioButton
        ProbabilityLTCES                matlab.ui.control.RadioButton
        ProbabilityGTCES                matlab.ui.control.RadioButton
        AverageCES                      matlab.ui.control.RadioButton
        CoralCoverButtonGroup           matlab.ui.container.ButtonGroup
        UnknownCC                       matlab.ui.control.RadioButton
        ProbabilityLTCC                 matlab.ui.control.RadioButton
        ProbabilityGTCC                 matlab.ui.control.RadioButton
        AverageCC                       matlab.ui.control.RadioButton
        ValuePES                        matlab.ui.control.NumericEditField
        ValueEditField_3Label           matlab.ui.control.Label
        ValueCES                        matlab.ui.control.NumericEditField
        ValueEditField_2Label           matlab.ui.control.Label
        ValueCC                         matlab.ui.control.NumericEditField
        ValueEditFieldLabel             matlab.ui.control.Label
        MakeMetricInferenceButton       matlab.ui.control.Button
        ByYearDropDown                  matlab.ui.control.DropDown
        ByYearDropDownLabel             matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        BBNVisualisationTab             matlab.ui.container.Tab
        PlotBBNButton                   matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        TradeoffsVisualisationTab       matlab.ui.container.Tab
        SpatialVisualisationTab         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Data % Description
        displayedIndices
        counterfIndices        
    end
   
    methods (Access = private)
        
        function intervention_inf_plot(app)

            % create bbn structure
            Names = {'RCP'; 'Years'; 'Guided'; 'PrSites';'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';'CultES';'ProvES'};
            ParentCell = cell(1,12);
            % first 9 noces have no parents
            for i = 1:9
                ParentCell{i} = [];
            end
            % coral cover and 2 ES have all other nodes as parents
            % initially
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;
           
            data_bbn = table2array(app.Data);
            % get rid of algorithm indicator for now
            data_bbn(:,2) = [];
            
            % create bbn crank orrelation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            % second matrix for ploting links with non-zero corr

            yrs = 10:10:50;
            F = cell(1,5);
            if strcmp(app.MetricDropDown.Value,'Coral Cover')
                metric_ind = 1;
                axstr = 'Coral Cover'
            elseif strcmp(app.MetricDropDown.Value,'Cultural ES')
                metric_ind = 2;
                axstr = 'Cultural ES'
            elseif strcmp(app.MetricDropDown.Value,'Provisional ES')
                metric_ind = 3;
                axstr = 'Provisional ES'
            end
            
            if app.CButton.Value
                PrSites = 3;
            elseif app.AButton.Value
                PrSites = 1;
            else 
                PrSites = 2;
            end
            
            % perform inference on selected interventions and plot 
            % check whether histogram is selected or time series and plot
            % accordingly
            
            if strcmp(app.PlotDropDown.Value,'Histogram')
                hold(app.UIAxes,'on')              
                % for each year simulated
                max1 = 0;
                % finer temporal grid to evaluate pdf on
                x = linspace(0,1,30);
                for l = 1:length(yrs)
                    % retrieve histogram for select metric under selected 
                    % intervention scenario
                    F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,str2double(app.Seed1DropDown.Value),...
                        str2double(app.Seed2DropDown.Value),str2double(app.SRMDropDown.Value),str2double(app.AsAdptDropDown.Value),...
                        str2double(app.NatAdptDropDown.Value)],R,data_bbn,'full',1000,'near');
                    hist_dat = F{l};
                    histogram(app.UIAxes,hist_dat{metric_ind},'Normalization','probability','NumBins',50);
                    % plot histogram of resulting distribution for metric (CC
                    % or ES)
%                     pd = fitdist(kde_dat{metric_ind},'kernel','Kernel','normal');
%                     pdf_dat = pdf(pd,x);
%                     plot(app.UIAxes,x,pdf_dat);
                    max1_temp = max(hist_dat{metric_ind});
                    if max1_temp>max1
                        max1 = max1_temp;
                    end
                end
                legend(app.UIAxes,'year 10','year 20','year 30','year 40','year 50','Interpreter','latex','FontSize',14);
                xlabel(app.UIAxes,axstr, 'Interpreter', 'latex','FontSize',14)
                ylabel(app.UIAxes, 'Probability','Interpreter','latex','FontSize',14)
                title(app.UIAxes,'Histogram with intervention','Interpreter','latex','FontSize',14)
                hold(app.UIAxes,'off')
                hold(app.UIAxes2,'on');     
                max2 =0;
                % repeat for non-intervention scenario
                for l = 1:length(yrs)
                    F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,0,0,0,0,0],R,data_bbn,'full',1000,'near');
                    hist_dat = F{l};
                    histogram(app.UIAxes2,hist_dat{metric_ind},'Normalization','probability','NumBins',50);
                    max2_temp = max(hist_dat{metric_ind});
                    if max2_temp>max2
                        max2 = max2_temp;
                    end
                end
                
                legend(app.UIAxes2,'year 10','year 20','year 30','year 40','year 50','Interpreter','latex','FontSize',14);
                xlabel(app.UIAxes2,axstr, 'Interpreter', 'latex','FontSize',14)
                ylabel(app.UIAxes2,'Probability','Interpreter','latex','FontSize',14)
                title(app.UIAxes2,'Histogram without intervention','Interpreter','latex','FontSize',14)    
                hold(app.UIAxes2,'off')
                
                % adjust range and domain
                app.UIAxes.XLim = [0 max1+0.01];
                app.UIAxes.YLim = [0 1];
                app.UIAxes2.XLim = [0 max2+0.01];
                app.UIAxes2.YLim = [0 1];
            elseif strcmp(app.PlotDropDown.Value,'Violin Plot')
                
                % select ydata based on values selected on interface
                if strcmp(app.MetricDropDown.Value,'Coral Cover')
                    ydata1 = reshape(app.Data.CoralCover(app.displayedIndices),10,length(app.Data.CoralCover(app.displayedIndices))/10);
                    ydata2 = reshape(app.Data.CoralCover(app.counterfIndices),10,length(app.Data.CoralCover(app.counterfIndices))/10);
                    axstr = 'Coral Cover';
                elseif strcmp(app.MetricDropDown.Value,'Cultural ES')
                    ydata1 = reshape(app.Data.Cult_ES(app.displayedIndices),10,length(app.Data.Cult_ES(app.displayedIndices))/10);
                    ydata2 = reshape(app.Data.Cult_ES(app.counterfIndices),10,length(app.Data.Cult_ES(app.counterfIndices))/10);
                    axstr = 'Cultural ES';
                elseif strcmp(app.MetricDropDown.Value,'Provisional ES')
                    ydata1 = reshape(app.Data.Prov_ES(app.displayedIndices),10,length(app.Data.Prov_ES(app.displayedIndices))/10);
                    ydata2 = reshape(app.Data.Prov_ES(app.counterfIndices),10,length(app.Data.Prov_ES(app.counterfIndices))/10); 
                    axstr = 'Provisional ES';
                end
%                 ydata1 = [];
%                 ydata2 = [];
                % perform inferences for intervention and non-intervention
              %  for l = 1:length(yrs)
%                     F = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,...
%                         str2double(app.Seed1DropDown.Value),str2double(app.Seed2DropDown.Value),...
%                         str2double(app.SRMDropDown.Value),str2double(app.AsAdptDropDown.Value),...
%                         str2double(app.NatAdptDropDown.Value)],R,data_bbn,'full',1000,'near');
%                     vi_dat = F{metric_ind};
%                     ydata1 = [ydata1 vi_dat(:)];
                %end
                
              %  for l = 1:length(yrs)
%                     F = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,0,0,0,0,0],R,data_bbn,'full',1000,'near');
%                     vi_dat = F{metric_ind};
%                     ydata2 = [ydata2 vi_dat(:)];
            %    end
                str = cell(size(yrs));
                str2 = str;
                % plot violin plot for scenario with intervention
                hold(app.UIAxes,'on')
                [~, mu, sigma, ~, ~] = al_goodplot(ydata1,yrs,app.UIAxes,0.6,[0.5195,0.5977,0]);
                
                % print average value for each year on plot
                for k = 1:length(mu)
                    str{k} = sprintf('$\\mu$: %1.3f', mu(k));
                end
                text(app.UIAxes,yrs,mu,str,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',14);
                ylabel(app.UIAxes,axstr,'Interpreter','latex','FontSize',14);
                xlabel(app.UIAxes,'Years','FontSize',14);
                legend(app.UIAxes,'hide')
                hold(app.UIAxes,'off')
                
                % plot violin plot for difference in intervention and no
                % intervention scenarios
                hold(app.UIAxes2,'on')
                [~, mu2, sigma2, ~, ~] = al_goodplot(ydata2,yrs,app.UIAxes2,0.6);
                
                % print averages
                for k = 1:length(mu2)
                    str2{k} = sprintf('$\\mu$: %1.3f',mu2(k));
                end
                text(app.UIAxes2,yrs,mu2,str2,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',14);
                ylabel(app.UIAxes2,axstr,'Interpreter','latex','FontSize',14);
                xlabel(app.UIAxes2,'Years','FontSize',14);
                legend(app.UIAxes2,'hide')
                hold(app.UIAxes2,'off')
                % adjust range and domain
                app.UIAxes.XLim = [5 60];
                app.UIAxes.YLim = [0 max(ydata1,[],'all')+0.01];
                app.UIAxes2.XLim = [5 60];
                app.UIAxes2.YLim = [0 max(ydata2,[],'all')+0.01];
                
            end
        end


        function metric_inf_plot(app)
            
            % to reference input values
            vals = [app.ValueCC.Value,app.ValueCES.Value,app.ValuePES.Value];
            strvals = {'CC','CES','PES'};
            
            % to store outputs
            str = '';
            
            % always use guided scenario for now
            Guided = 1;
            
            % check scenario params (sites,year and alg)
            
            year = str2double(app.ByYearDropDown.Value); 
            
            if app.AButton.Value
                PrSites = 1;
            elseif app.BButton.Value
                PrSites = 2;
            elseif app.CButton.Value
                PrSites = 3;
            end
            
            if app.VIKORButton_2.Value
                alg_ind = 1;
            elseif app.TOPSISButton_2.Value
                alg_ind = 2;
            elseif app.RankorderingButton.Value
                alg_ind = 3;
            end
            
            year = str2double(app.ByYearDropDown.Value);
            
            % create bbn structure using BANSHEE syntax
            Names = {'RCP';'Years'; 'Guided'; 'PrSites';...
                'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';...
                'CultES';'ProvES'};
            
            ParentCell = cell(1,12);
            for i = 1:9
                ParentCell{i} = [];
            end
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;
            
            % format data
            data_bbn = table2array(app.Data);
            data_bbn(:,2) = [];
            % create bbn rank correlation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            
            % create logical vectors for options
            av_vec = [app.AverageCC.Value app.AverageCES.Value app.AveragePES.Value];
            prob_vec = [app.ProbabilityLTCC.Value app.ProbabilityLTCES.Value app.ProbabilityLTPES.Value;
                        app.ProbabilityGTCC.Value app.ProbabilityGTCES.Value app.ProbabilityGTPES.Value];
            unknown_vec = [app.UnknownCC.Value app.UnknownCES.Value app.UnknownPES.Value];
            
            % go through all possible combinations 
            if sum(av_vec) == 3               
                % if all averages first
                F0 = inference([1:4 10:12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, vals],R,data_bbn,'mean',1000,'near');
            elseif sum(av_vec) == 2
                % if 2 are averages, find out which are probabilities and
                % which are unknowns
                
                % if one is a prob
                if sum(prob_vec,'all') == 1
                    indprob = find(sum(prob_vec,1) == 1);
                    indav = find(av_vec == 1)
                    G = inference([1:4 9+indav],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites,vals(indav)],R,data_bbn,'full',1000,'near');
                    F0 = zeros(5,1)
                    for j = 1:5
                        F0(j) = mean(G{j});
                    end
                    dist = G{end};
                    % calculate probability given > or less than inputs
                    if sum(prob_vec(1,:)) == 1
                        Pr = sum(dist(dist<vals(indprob)))/sum(dist);
                        str = sprintf(strcat('Pr(',strvals{indprob},'<%1.2f) = %1.2f'),vals(indprob),Pr);
                        app.InferenceresultsTextArea.Value = str;                           
                    elseif sum(prob_vec(2,:)) == 1
                        Pr = sum(dist(dist>vals(indprob)))/sum(dist);
                        str = sprintf(strcat('Pr(',strvals{indprob},'>%1.2f) = %1.2f'),vals(indprob),Pr);
                        app.InferenceresultsTextArea.Value = str;
                    end
                    % plot histograms of probability
                    hold(app.UIAxes3,'on')
                    histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                    legend(app.UIAxes3,strcat('$Pr(',strvals{indprob},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{indprob},'$'),'Interpreter','latex')
                    
                % if one is unknown
                elseif sum(unknown_vec) == 1
                    indun = find(unknown_vec == 1);
                    indav = find(av_vec == 1)
                    G = inference([1:4 9+indav],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites,vals(indav)],R,data_bbn,'full',1000,'near');
                    F0 = zeros(6,1)
                    for j = 1:6
                        F0(j) = mean(G{j});
                    end
                    dist = G{end};
                    str = sprintf(strcat('Av. ',strvals{indun},': %1.2f'),F0(end));
                    app.InferenceresultsTextArea.Value = str;
                    
                    % plot histogram of probability
                    hold(app.UIAxes3,'on')
                    histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                    legend(app.UIAxes3,strcat('$Pr(',strvals{indun},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{indun},'$'),'Interpreter','latex')
                end

            elseif sum(av_vec) == 1
                % if one is an average, find out which are probabilities
                % and which are unknowns
                
                % if 2 are probs
                if sum(prob_vec,'all') == 2
                    indprob = find(sum(prob_vec,1) == 1);
                    indav = find(av_vec == 1);
                    G = inference([1:4 9+indav],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites,vals(indav)],R,data_bbn,'full',1000,'near');
                    F0 = zeros(5,1)
                    for j = 1:5
                        F0(j) = mean(G{j});
                    end
                    dists = [G{end-1}, G{end}];

                    % calculate probability given > or less than inputs
                    hold(app.UIAxes3,'on')
                    for k = 1:2
                        dist = dists(:,k);
                        if prob_vec(1,indprob(k)) == 1
                            Pr1 = sum(dist(dist<vals(indprob(k))))/sum(dist);
                            histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                            str = sprintf(strcat(str,'\n','Pr(',strvals{indprob(k)},'<%1.2f) = %1.2f'),vals(indprob(k)),Pr1);                        
                        elseif prob_vec(2,indprob(k)) == 1
                            Pr1 = sum(dist(dist>vals(indprob(k))))/sum(dist);
                            histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                            str = sprintf(strcat(str,'\n','Pr(',strvals{indprob(k)},'>%1.2f) = %1.2f'),vals(indprob(k)),Pr1); 
                        end
                    end

                    app.InferenceresultsTextArea.Value = str;  
                    legend(app.UIAxes3,strcat('$Pr(',strvals{indprob(1)},')$'),strcat('$Pr(',strvals{indprob(2)},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{indprob(1)},'/',strvals{indprob(2)},'$'),'Interpreter','latex')
                % if one is a prob (and one is unknown)
                elseif sum(prob_vec,'all') == 1
                    indprob = find(sum(prob_vec,1) == 1);
                    indav = find(av_vec == 1);
                    indun = find(unknown_vec == 1);
                    G = inference([1:4 9+indav],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites,vals(indav)],R,data_bbn,'full',1000,'near');
                    F0 = zeros(5,1)
                    for j = 1:5
                        F0(j) = mean(G{j});
                    end
                    distp = G{end-3+indprob};
                    distu = G{end-3+indun};
                    % calculate probability given > or less than inputs
                    if sum(prob_vec(1,:)) == 1
                        Pr = sum(distp(distp<vals(indprob)))/sum(distp);
                        str = sprintf(strcat('Pr(',strvals{indprob},'<%1.2f) = %1.2f'),vals(indprob),Pr);
                        
                    elseif sum(prob_vec(2,:)) == 1
                        Pr = sum(distp(distp>vals(indprob)))/sum(distp);
                        str = sprintf(strcat('Pr(',strvals{indprob},'>%1.2f) = %1.2f'),vals(indprob),Pr);                        
                    end
                    str = sprintf(strcat(str, '\n ','Av. ',strvals{indun},': %1.2f'),mean(distu));
                    app.InferenceresultsTextArea.Value = str;  
                    
                    % plot histograms of probability
                    hold(app.UIAxes3,'on')
                    histogram(app.UIAxes3,distp,'NumBins',30,'Normalization','Probability'); 
                    histogram(app.UIAxes3,distu,'NumBins',30,'Normalization','Probability'); 
                    legend(app.UIAxes3,strcat('$Pr(',strvals{indprob},')$'),strcat('$Pr(',strvals{indun},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{indprob},'/',strvals{indun},'$'),'Interpreter','latex')
                % if both unknown
                elseif sum(unknown_vec) == 2
                    indun = find(unknown_vec == 1);
                    indav = find(av_vec == 1)
                    G = inference([1:4 9+indav],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites,vals(indav)],R,data_bbn,'full',1000,'near');
                    F0 = zeros(6,1)
                    for j = 1:6
                        F0(j) = mean(G{j});
                    end
                    dist1 = G{end-1};
                    dist2 = G{end};
                    str = sprintf(strcat('Av. ',strvals{indun(1)},': %1.2f\n','Av. ',strvals{indun(2)},': %1.2f'),mean(dist1),mean(dist2));
                    app.InferenceresultsTextArea.Value = str;
                    
                    % plot histogram of probability
                    hold(app.UIAxes3,'on')
                    histogram(app.UIAxes3,dist1,'NumBins',30,'Normalization','Probability'); 
                    histogram(app.UIAxes3,dist2,'NumBins',30,'Normalization','Probability'); 
                    legend(app.UIAxes3,strcat('$Pr(',strvals{indun(1)},')$'),strcat('$Pr(',strvals{indun(2)},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{indun(1)},'/',strvals{indun(2)},'$'),'Interpreter','latex')
                end
             
            elseif sum(av_vec) == 0
            % if none are averages, find out which are unknown and which
            % are probabilities
            
                % if all are probabilities
                if sum(prob_vec,'all') == 3
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites],R,data_bbn,'full',1000,'near');
                    F0 = zeros(5,1)
                    for j = 1:5
                        F0(j) = mean(G{j});
                    end
                    dists = [G{end-2},G{end-1},G{end}];
                    
                    % calculate probability given > or less than inputs
                    % and plot histograms
                     hold(app.UIAxes3,'on')
                    for l = 1:3
                        if prob_vec(1,l) == 1
                            dist = dists(:,l);
                            histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                            Pr = sum(dist(dist<vals(l)))/sum(dist);
                            str = sprintf(strcat(str,'\n','Pr(',strvals{l},'<%1.2f) = %1.2f'),vals(l),Pr);                        
                        elseif prob_vec(2,l) == 1
                            dist = dists(:,l);
                            histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                            Pr = sum(dist(dist>vals(l)))/sum(dist);
                            str = sprintf(strcat(str,'\n','Pr(',strvals{l},'>%1.2f) = %1.2f'),vals(l),Pr); 
                        end
                    end
                    app.InferenceresultsTextArea.Value = str;
                    legend(app.UIAxes3,strcat('$Pr(',strvals{1},')$'),strcat('$Pr(',strvals{2},')$'),strcat('$Pr(',strvals{3},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{1},'/',strvals{2},'/',strvals{3},'$'),'Interpreter','latex')
                % if 2 are probabilities
                elseif sum(prob_vec,'all') == 2
                    indprob = find(sum(prob_vec,1) == 1);
                    indun = find(unknown_vec == 1);
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites],R,data_bbn,'full',1000,'near');
                    F0 = zeros(5,1)
                    for j = 1:5
                        F0(j) = mean(G{j});
                    end
                    distsp = [G{end-3+indprob(1)} G{end-3+indprob(2)}];
                    distu = G{end-3+indun};
                    
                    % calculate probability given > or less than inputs
                    hold(app.UIAxes3,'on')
                    for k = 1:2
                        dist = distsp(:,k);
                        if prob_vec(1,indprob(k)) == 1
                            Pr1 = sum(dist(dist<vals(indprob(k))))/sum(dist);
                            histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                            str = sprintf(strcat(str,'\n','Pr(',strvals{indprob(k)},'<%1.2f) = %1.2f'),vals(indprob(k)),Pr1);                        
                        elseif prob_vec(2,indprob(k)) == 1
                            Pr1 = sum(dist(dist>vals(indprob(k))))/sum(dist);
                            histogram(app.UIAxes3,dist,'NumBins',30,'Normalization','Probability'); 
                            str = sprintf(strcat(str,'\n','Pr(',strvals{indprob(k)},'>%1.2f) = %1.2f'),vals(indprob(k)),Pr1); 
                        end
                    end
                    histogram(app.UIAxes3,distu,'NumBins',30,'Normalization','Probability'); 
                    str = sprintf(strcat(str,'\n','Av. ',strvals{indun}, ': %1.2f'),mean(distu)); 
                    app.InferenceresultsTextArea.Value = str;
                    
                    legend(app.UIAxes3,strcat('$Pr(',strvals{1},')$'),strcat('$Pr(',strvals{2},')$'),strcat('$Pr(',strvals{3},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{1},'/',strvals{2},'/',strvals{3},'$'),'Interpreter','latex')
                % if only one is a probability
                elseif sum(prob_vec, 'all') == 1
                    indprob = find(sum(prob_vec,1) == 1);
                    indun = find(unknown_vec == 1);
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10,year, Guided,PrSites],R,data_bbn,'full',1000,'near');
                    F0 = zeros(5,1)
                    for j = 1:5
                        F0(j) = mean(G{j});
                    end
                    distp = G{end-3+indprob} 
                    distsu = [G{end-3+indun(1)} G{end-3+indun(2)}];
                    
                    % calculate probability given > or less than inputs
                    hold(app.UIAxes3,'on')

                    if prob_vec(1,indprob) == 1
                        Pr1 = sum(distp(distp<vals(indprob)))/sum(distp);
                        histogram(app.UIAxes3,distp,'NumBins',30,'Normalization','Probability'); 
                        str = sprintf(strcat(str,'\n','Pr(',strvals{indprob},'<%1.2f) = %1.2f'),vals(indprob),Pr1);                        
                    elseif prob_vec(2,indprob) == 1
                        Pr1 = sum(distp(distp>vals(indprob)))/sum(distp);
                        histogram(app.UIAxes3,distp,'NumBins',30,'Normalization','Probability'); 
                        str = sprintf(strcat(str,'\n','Pr(',strvals{indprob},'>%1.2f) = %1.2f'),vals(indprob),Pr1); 
                    end
                    
                    histogram(app.UIAxes3,distu,'NumBins',30,'Normalization','Probability'); 
                    str = sprintf(strcat(str,'\n','Av. ',strvals{indun(1)}, ': %1.2f','\n','Av. ',strvals{indun(2)}, ': %1.2f'),mean(distsu(:,1)),mean(distsu(:,2))); 
                    app.InferenceresultsTextArea.Value = str;
                    
                    legend(app.UIAxes3,strcat('$Pr(',strvals{1},')$'),strcat('$Pr(',strvals{2},')$'),strcat('$Pr(',strvals{3},')$'),'Interpreter','latex');
                    ylabel(app.UIAxes3,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes3,strcat('$',strvals{1},'/',strvals{2},'/',strvals{3},'$'),'Interpreter','latex')
                end
                
                
            end
            % retrieve inference values for parameters
            F0(F0<0) = 0; % should change this later to have a warning if geeting nonsensical values
            F0 = round(F0,4);
            app.Seed1Gauge.Value = F0(1);
            app.Seed2Gauge.Value = F0(2);
            app.SRMGauge.Value = F0(3);
            app.AsAdtGauge.Value = F0(4);
            app.NatAdtGauge.Value = F0(5);
            
            % Seed1 light colour
            if app.Seed1Gauge.Value == 0
                % if zero, light is red
                app.Seed1Lamp.Color = [1,69/255,0];
            elseif (app.Seed1Gauge.Value>0)&&(app.Seed1Gauge.Value<=(0.25*app.Seed1Gauge.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.Seed1Lamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.Seed1Lamp.Color = [50/255,205/255,50/255];
            end
            
                        
            % Seed2 light colour
            if app.Seed2Gauge.Value == 0
                % if zero, light is red
                app.Seed2Lamp.Color = [1,69/255,0];
            elseif (app.Seed1Gauge.Value>0)&&(app.Seed1Gauge.Value<=(0.25*app.Seed1Gauge.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.Seed2Lamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.Seed2Lamp.Color = [50/255,205/255,50/255];
            end
            
            % SRM light colour
            if app.SRMGauge.Value == 0
                % if zero, light is red
                app.SRMLamp.Color = [1,69/255,0];
            elseif (app.SRMGauge.Value>0)&&(app.SRMGauge.Value<=(0.25*app.SRMGauge.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.SRMLamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.SRMLamp.Color = [50/255,205/255,50/255];
            end
            
            % NatAdt light colour
            if app.NatAdtGauge.Value == 0
                % if zero, light is red
                app.NatAdtLamp.Color = [1,69/255,0];
            elseif (app.NatAdtGauge.Value>0)&&(app.NatAdtGauge.Value<=(0.25*app.NatAdtGauge.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.NatAdtLamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.NatAdtLamp.Color = [50/255,205/255,50/255];
            end
            
             if app.AsAdtGauge.Value == 0
                % if zero, light is red
                app.AsAdtLamp.Color = [1,69/255,0];
             elseif (app.AsAdtGauge.Value>0)&&(app.AsAdtGauge.Value<=(0.25*app.AsAdtGauge.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.AsAdtLamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.AsAdtLamp.Color = [50/255,205/255,50/255];
            end

            
        end
        
        function plot_bbn(app)
            % create bbn structure
            Names = {'RCP'; 'Years'; 'Guided'; 'PrSites';'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';'CultES';'ProvES'};
            ParentCell = cell(1,12);
            % first 9 noces have no parents
            for i = 1:9
                ParentCell{i} = [];
            end
            % coral cover and 2 ES have all other nodes as parents
            % initially
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;

%             ParentCell2 = ParentCell;
           
            data_bbn = table2array(app.Data);
            % get rid of algorithm indicator for now
            data_bbn(:,2) = [];
            
            % create bbn crank orrelation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            % second matrix for ploting links with non-zero corr
%             R2 = R;
            
            % find links with near zero and zero correlation and get rid of
            % them
%             R2(abs(R2)<=10e-4) = NaN;
%             R2(abs(R2)==0) = NaN;
%             M = 1:9;
%             for k = 1:9
%                 if isnan(R2(k,10))
%                     M = M(M~=k);
%                     ParentCell2{10} = M;
%                 end
%                 if isnan(R2(k,11))
%                     M = M(M~=k);
%                     ParentCell2{11} = M;
%                 end
%                 if isnan(R2(k,12))
%                     M = M(M~=k);
%                     ParentCell2{12} = M;
%                 end
%             end
            
            % create BBN visualisation
            bn_visualize(ParentCell,R,Names,app.UIAxes4);
        end
        
        function filter_data(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the RCP control 
            tempIndices = [tempIndices, app.Data.RCP == str2double(app.RCPKnob.Value)*10];
            
             % Append a column to tempIndices to indicate data that satisfies MCDA control
            if app.OrderRankingButton.Value && ~app.VIKORButton.Value && ~app.TOPSISButton.Value
                tempIndices = [tempIndices, app.Data.MCDAAlgs == 1];
            elseif ~app.OrderRankingButton.Value && app.VIKORButton.Value && ~app.TOPSISButton.Value
                tempIndices = [tempIndices, app.Data.MCDAAlgs == 2];
            elseif ~app.OrderRankingButton.Value && ~app.VIKORButton.Value && app.TOPSISButton.Value
                tempIndices = [tempIndices, app.Data.MCDAAlgs == 3];
            end
            

            tempIndices = [tempIndices, app.Data.Guided == 1];

            
            % Append a column to tempIndices to indicate data that satisfies the Site control
            if app.BButton.Value && ~app.CButton.Value && ~app.AButton.Value
                tempIndices = [tempIndices, app.Data.PrSites == 2];
            elseif app.CButton.Value && ~app.BButton.Value && ~app.AButton.Value
                tempIndices = [tempIndices, app.Data.PrSites == 3];
            elseif app.AButton.Value && ~app.BButton.Value && ~app.CButton.Value
                tempIndices = [tempIndices, app.Data.PrSites == 1];
            end
            
            % counterfactual indexing will be the same except for
            % intervention indexing
            tempIndices2 = tempIndices;
            
            % Append a column to tempIndices to indicate data that satisfies the Seed1 control
            tempIndices = [tempIndices, app.Data.Seed1 == str2double(app.Seed1DropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.Seed1 == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Seed2 control
            tempIndices = [tempIndices, app.Data.Seed2 == str2double(app.Seed2DropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.Seed2 == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the SRM control
            tempIndices = [tempIndices, app.Data.SRM == str2double(app.SRMDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.SRM == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Assisted Adaptation control
            tempIndices = [tempIndices, app.Data.AssistedAdapt == str2double(app.AsAdptDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.AssistedAdapt == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Natural Adaptation control
            tempIndices = [tempIndices, app.Data.NaturalAdapt == str2double(app.NatAdptDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.NaturalAdapt == 0];
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
            app.counterfIndices = (sum(tempIndices2,2)/size(tempIndices2,2) == 1);
            
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Store the data in the app's properties
            app.Data = readtable('ADRIA_BBN_Data.csv');
        end

        % Button pushed function: MakeInterventionInferenceButton
        function MakeInterventionInferenceButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            filter_data(app)
            intervention_inf_plot(app)
        end

        % Button pushed function: MakeMetricInferenceButton
        function MakeMetricInferenceButtonPushed(app, event)
            cla(app.UIAxes3)
            metric_inf_plot(app)
        end

        % Button pushed function: PlotBBNButton
        function PlotBBNButtonPushed(app, event)
            cla(app.UIAxes4)
            plot_bbn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterventionInferenceTab = uitab(app.TabGroup);
            app.InterventionInferenceTab.Title = 'Intervention Inference';
            app.UIAxes = uiaxes(app.InterventionInferenceTab);
            title(app.UIAxes, 'Counterfactual', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Time', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Metric', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.UIAxes2 = uiaxes(app.InterventionInferenceTab);
            title(app.UIAxes2, 'Intervention', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'Time', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'Metric', 'Interpreter', 'latex')
            zlabel(app.UIAxes2, 'Z', 'Interpreter', 'latex')
            app.InterventionSelectionPanel = uipanel(app.InterventionInferenceTab);
            app.InterventionSelectionPanel.Title = 'Intervention Selection';
            app.Seed1DropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.Seed1DropDownLabel.Text = 'Seed 1';
            app.Seed1DropDown = uidropdown(app.InterventionSelectionPanel);
            app.Seed1DropDown.Items = {'0', '0.0005', '0.001'};
            app.Seed1DropDown.Value = '0';
            app.Seed2DropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.Seed2DropDownLabel.Text = 'Seed 2';
            app.Seed2DropDown = uidropdown(app.InterventionSelectionPanel);
            app.Seed2DropDown.Items = {'0', '0.0005', '0.001'};
            app.Seed2DropDown.Value = '0';
            app.SRMDropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.SRMDropDownLabel.Text = 'SRM';
            app.SRMDropDown = uidropdown(app.InterventionSelectionPanel);
            app.SRMDropDown.Items = {'0', '6', '12', ''};
            app.SRMDropDown.Value = '0';
            app.AsAdptDropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.AsAdptDropDownLabel.Text = 'As. Adpt. ';
            app.AsAdptDropDown = uidropdown(app.InterventionSelectionPanel);
            app.AsAdptDropDown.Items = {'0', '6', '12'};
            app.AsAdptDropDown.Value = '0';
            app.NatAdptDropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.NatAdptDropDownLabel.Text = 'Nat. Adpt.';
            app.NatAdptDropDown = uidropdown(app.InterventionSelectionPanel);
            app.NatAdptDropDown.Items = {'0', '0.05', '0.1'};
            app.NatAdptDropDown.Value = '0';
            app.MakeInterventionInferenceButton = uibutton(app.InterventionSelectionPanel, 'push');
            app.MakeInterventionInferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MakeInterventionInferenceButtonPushed, true);
            app.MakeInterventionInferenceButton.Text = {'Make Intervention'; 'Inference'};
            app.SiteGroupButtonGroup = uibuttongroup(app.InterventionInferenceTab);
            app.SiteGroupButtonGroup.Title = 'Site Group';
            app.AButton = uiradiobutton(app.SiteGroupButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uiradiobutton(app.SiteGroupButtonGroup);
            app.BButton.Text = 'B';
            app.CButton = uiradiobutton(app.SiteGroupButtonGroup);
            app.CButton.Text = 'C';
            app.MCDAAlgorithmButtonGroup = uibuttongroup(app.InterventionInferenceTab);
            app.MCDAAlgorithmButtonGroup.Title = 'MCDA Algorithm';
            app.OrderRankingButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.OrderRankingButton.Text = 'Order Ranking';
            app.OrderRankingButton.Value = true;
            app.TOPSISButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.TOPSISButton.Text = 'TOPSIS';
            app.VIKORButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.VIKORButton.Text = 'VIKOR';
            app.RCPKnobLabel = uilabel(app.InterventionInferenceTab);
            app.RCPKnobLabel.Text = 'RCP';
            app.RCPKnob = uiknob(app.InterventionInferenceTab, 'discrete');
            app.RCPKnob.Items = {'2.6', '4.5', '6.0', '8.5'};
            app.RCPKnob.Value = '2.6';
            app.MetricDropDownLabel = uilabel(app.InterventionInferenceTab);
            app.MetricDropDownLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.InterventionInferenceTab);
            app.MetricDropDown.Items = {'Coral Cover', 'Cultural ES', 'Provisional ES'};
            app.MetricDropDown.Value = 'Coral Cover';
            app.PlotDropDownLabel = uilabel(app.InterventionInferenceTab);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.InterventionInferenceTab);
            app.PlotDropDown.Items = {'Violin Plot', 'Histogram'};
            app.PlotDropDown.Value = 'Violin Plot';
            app.MetricInferenceTab = uitab(app.TabGroup);
            app.MetricInferenceTab.Title = 'Metric Inference';
            app.UIAxes3 = uiaxes(app.MetricInferenceTab);
            xlabel(app.UIAxes3, 'Metric', 'Interpreter', 'latex')
            ylabel(app.UIAxes3, 'Probability', 'Interpreter', 'latex')
            zlabel(app.UIAxes3, 'Z', 'Interpreter', 'latex')
            app.InferenceparametersPanel = uipanel(app.MetricInferenceTab);
            app.InferenceparametersPanel.Title = 'Inference parameters';
            app.ByYearDropDownLabel = uilabel(app.InferenceparametersPanel);
            app.ByYearDropDownLabel.Text = 'By Year';
            app.ByYearDropDown = uidropdown(app.InferenceparametersPanel);
            app.ByYearDropDown.Items = {'10', '20', '30', '40', '50'};
            app.ByYearDropDown.Value = '10';
            app.MakeMetricInferenceButton = uibutton(app.InferenceparametersPanel, 'push');
            app.MakeMetricInferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MakeMetricInferenceButtonPushed, true);
            app.MakeMetricInferenceButton.Text = 'Make Metric Inference';
            app.ValueEditFieldLabel = uilabel(app.InferenceparametersPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueCC = uieditfield(app.InferenceparametersPanel, 'numeric');
            app.ValueCC.Limits = [0 1];
            app.ValueEditField_2Label = uilabel(app.InferenceparametersPanel);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueCES = uieditfield(app.InferenceparametersPanel, 'numeric');
            app.ValueCES.Limits = [0 1];
            app.ValueEditField_3Label = uilabel(app.InferenceparametersPanel);
            app.ValueEditField_3Label.Text = 'Value';
            app.ValuePES = uieditfield(app.InferenceparametersPanel, 'numeric');
            app.ValuePES.Limits = [0 1];
            app.CoralCoverButtonGroup = uibuttongroup(app.InferenceparametersPanel);
            app.CoralCoverButtonGroup.Title = 'Coral Cover';
            app.AverageCC = uiradiobutton(app.CoralCoverButtonGroup);
            app.AverageCC.Text = 'Average';
            app.AverageCC.Value = true;
            app.ProbabilityGTCC = uiradiobutton(app.CoralCoverButtonGroup);
            app.ProbabilityGTCC.Text = 'Probability >';
            app.ProbabilityLTCC = uiradiobutton(app.CoralCoverButtonGroup);
            app.ProbabilityLTCC.Text = 'Probability <';
            app.UnknownCC = uiradiobutton(app.CoralCoverButtonGroup);
            app.UnknownCC.Text = 'Unknown';
            app.CulturalESButtonGroup = uibuttongroup(app.InferenceparametersPanel);
            app.CulturalESButtonGroup.Title = 'Cultural ES';
            app.AverageCES = uiradiobutton(app.CulturalESButtonGroup);
            app.AverageCES.Text = 'Average';
            app.AverageCES.Value = true;
            app.ProbabilityGTCES = uiradiobutton(app.CulturalESButtonGroup);
            app.ProbabilityGTCES.Text = 'Probability >';
            app.ProbabilityLTCES = uiradiobutton(app.CulturalESButtonGroup);
            app.ProbabilityLTCES.Text = 'Probability <';
            app.UnknownCES = uiradiobutton(app.CulturalESButtonGroup);
            app.UnknownCES.Text = 'Unknown';
            app.ProvisionalESButtonGroup = uibuttongroup(app.InferenceparametersPanel);
            app.ProvisionalESButtonGroup.Title = 'Provisional ES';
            app.AveragePES = uiradiobutton(app.ProvisionalESButtonGroup);
            app.AveragePES.Text = 'Average';
            app.AveragePES.Value = true;
            app.ProbabilityGTPES = uiradiobutton(app.ProvisionalESButtonGroup);
            app.ProbabilityGTPES.Text = 'Probability >';
            app.ProbabilityLTPES = uiradiobutton(app.ProvisionalESButtonGroup);
            app.ProbabilityLTPES.Text = 'Probability <';
            app.UnknownPES = uiradiobutton(app.ProvisionalESButtonGroup);
            app.UnknownPES.Text = 'Unknown';
            app.NoninterventionparametersPanel = uipanel(app.MetricInferenceTab);
            app.NoninterventionparametersPanel.Title = 'Non- intervention parameters';
            app.RCPKnob_2Label = uilabel(app.NoninterventionparametersPanel);
            app.RCPKnob_2Label.Text = 'RCP';
            app.RCPKnob_2 = uiknob(app.NoninterventionparametersPanel, 'discrete');
            app.RCPKnob_2.Items = {'2.6', '4.5', '6.0', '8.5'};
            app.RCPKnob_2.Value = '2.6';
            app.SiteSelectionButtonGroup = uibuttongroup(app.NoninterventionparametersPanel);
            app.SiteSelectionButtonGroup.Title = 'Site Selection';
            app.AButton_2 = uiradiobutton(app.SiteSelectionButtonGroup);
            app.AButton_2.Text = 'A';
            app.AButton_2.Value = true;
            app.BButton_2 = uiradiobutton(app.SiteSelectionButtonGroup);
            app.BButton_2.Text = 'B';
            app.CButton_2 = uiradiobutton(app.SiteSelectionButtonGroup);
            app.CButton_2.Text = 'C';
            app.MCDAAlgorithmButtonGroup_2 = uibuttongroup(app.NoninterventionparametersPanel);
            app.MCDAAlgorithmButtonGroup_2.Title = 'MCDA Algorithm';
            app.RankorderingButton = uiradiobutton(app.MCDAAlgorithmButtonGroup_2);
            app.RankorderingButton.Text = 'Rank ordering';
            app.RankorderingButton.Value = true;
            app.TOPSISButton_2 = uiradiobutton(app.MCDAAlgorithmButtonGroup_2);
            app.TOPSISButton_2.Text = 'TOPSIS';
            app.VIKORButton_2 = uiradiobutton(app.MCDAAlgorithmButtonGroup_2);
            app.VIKORButton_2.Text = 'VIKOR';
            app.InterventionlevelsPanel = uipanel(app.MetricInferenceTab);
            app.InterventionlevelsPanel.Title = 'Intervention levels';
            app.Seed1LampLabel = uilabel(app.InterventionlevelsPanel);
            app.Seed1LampLabel.Text = 'Seed 1';
            app.Seed1Lamp = uilamp(app.InterventionlevelsPanel);
            app.Seed2LampLabel = uilabel(app.InterventionlevelsPanel);
            app.Seed2LampLabel.Text = 'Seed 2';
            app.Seed2Lamp = uilamp(app.InterventionlevelsPanel);
            app.SRMLampLabel = uilabel(app.InterventionlevelsPanel);
            app.SRMLampLabel.Text = 'SRM';
            app.SRMLamp = uilamp(app.InterventionlevelsPanel);
            app.NatAdtLampLabel = uilabel(app.InterventionlevelsPanel);
            app.NatAdtLampLabel.Text = 'Nat. Adt.';
            app.NatAdtLamp = uilamp(app.InterventionlevelsPanel);
            app.AsAdtLampLabel = uilabel(app.InterventionlevelsPanel);
            app.AsAdtLampLabel.Text = 'As. Adt.';
            app.AsAdtLamp = uilamp(app.InterventionlevelsPanel);
            app.Seed1Gauge = uigauge(app.InterventionlevelsPanel, 'linear');
            app.Seed1Gauge.Limits = [0 0.001];
            app.Seed2Gauge = uigauge(app.InterventionlevelsPanel, 'linear');
            app.Seed2Gauge.Limits = [0 0.001];
            app.SRMGauge = uigauge(app.InterventionlevelsPanel, 'linear');
            app.SRMGauge.Limits = [0 12];
            app.NatAdtGauge = uigauge(app.InterventionlevelsPanel, 'linear');
            app.NatAdtGauge.Limits = [0 0.1];
            app.NatAdtGauge.MajorTicks = [0 0.05 0.1];
            app.AsAdtGauge = uigauge(app.InterventionlevelsPanel, 'linear');
            app.AsAdtGauge.Limits = [0 12];
            app.InferenceresultsTextArea = uitextarea(app.MetricInferenceTab);
            app.BBNVisualisationTab = uitab(app.TabGroup);
            app.BBNVisualisationTab.Title = 'BBN Visualisation';
            app.UIAxes4 = uiaxes(app.BBNVisualisationTab);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.PlotBBNButton = uibutton(app.BBNVisualisationTab, 'push');
            app.PlotBBNButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBBNButtonPushed, true);
            app.PlotBBNButton.Text = 'Plot BBN';
            app.TradeoffsVisualisationTab = uitab(app.TabGroup);
            app.TradeoffsVisualisationTab.Title = 'Tradeoffs Visualisation';
            app.SpatialVisualisationTab = uitab(app.TabGroup);
            app.SpatialVisualisationTab.Title = 'Spatial Visualisation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADRIA_BBN_app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end