classdef ADRIA_BBN_app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InterventionInferenceTab        matlab.ui.container.Tab
        PlotDropDown                    matlab.ui.control.DropDown
        PlotDropDownLabel               matlab.ui.control.Label
        MetricDropDown                  matlab.ui.control.DropDown
        MetricDropDownLabel             matlab.ui.control.Label
        RCPKnob                         matlab.ui.control.DiscreteKnob
        RCPKnobLabel                    matlab.ui.control.Label
        MCDAAlgorithmButtonGroup        matlab.ui.container.ButtonGroup
        VIKORButton                     matlab.ui.control.RadioButton
        TOPSISButton                    matlab.ui.control.RadioButton
        OrderRankingButton              matlab.ui.control.RadioButton
        SiteGroupButtonGroup            matlab.ui.container.ButtonGroup
        CButton                         matlab.ui.control.RadioButton
        BButton                         matlab.ui.control.RadioButton
        AButton                         matlab.ui.control.RadioButton
        InterventionSelectionPanel      matlab.ui.container.Panel
        MakeInterventionInferenceButton  matlab.ui.control.Button
        NatAdptDropDown                 matlab.ui.control.DropDown
        NatAdptDropDownLabel            matlab.ui.control.Label
        AsAdptDropDown                  matlab.ui.control.DropDown
        AsAdptDropDownLabel             matlab.ui.control.Label
        SRMDropDown                     matlab.ui.control.DropDown
        SRMDropDownLabel                matlab.ui.control.Label
        Seed2DropDown                   matlab.ui.control.DropDown
        Seed2DropDownLabel              matlab.ui.control.Label
        Seed1DropDown                   matlab.ui.control.DropDown
        Seed1DropDownLabel              matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MetricInferenceTab              matlab.ui.container.Tab
        InterventionlevelsPanel         matlab.ui.container.Panel
        NoninterventionparametersPanel  matlab.ui.container.Panel
        MCDAAlgorithmButtonGroup_2      matlab.ui.container.ButtonGroup
        VIKORButton_2                   matlab.ui.control.RadioButton
        TOPSISButton_2                  matlab.ui.control.RadioButton
        RankorderingButton              matlab.ui.control.RadioButton
        SiteSelectionButtonGroup        matlab.ui.container.ButtonGroup
        CButton_2                       matlab.ui.control.RadioButton
        BButton_2                       matlab.ui.control.RadioButton
        AButton_2                       matlab.ui.control.RadioButton
        RCPKnob_2                       matlab.ui.control.DiscreteKnob
        RCPKnob_2Label                  matlab.ui.control.Label
        InferenceparametersPanel        matlab.ui.container.Panel
        MakeMetricInferenceButton       matlab.ui.control.Button
        ByYearDropDown                  matlab.ui.control.DropDown
        ByYearDropDownLabel             matlab.ui.control.Label
        Tree3                           matlab.ui.container.CheckBoxTree
        ProvisionalESNode               matlab.ui.container.TreeNode
        ProbabilityNode_4               matlab.ui.container.TreeNode
        Node_2                          matlab.ui.container.TreeNode
        Node                            matlab.ui.container.TreeNode
        AverageNode_4                   matlab.ui.container.TreeNode
        UnknownNode_4                   matlab.ui.container.TreeNode
        Tree2                           matlab.ui.container.CheckBoxTree
        CulturalESNode                  matlab.ui.container.TreeNode
        ProbabilityNode_3               matlab.ui.container.TreeNode
        Node_6                          matlab.ui.container.TreeNode
        Node_5                          matlab.ui.container.TreeNode
        AverageNode_3                   matlab.ui.container.TreeNode
        UnknownNode_3                   matlab.ui.container.TreeNode
        Tree                            matlab.ui.container.CheckBoxTree
        CoralCoverNode_2                matlab.ui.container.TreeNode
        ProbabilityNode_2               matlab.ui.container.TreeNode
        Node_4                          matlab.ui.container.TreeNode
        Node_3                          matlab.ui.container.TreeNode
        AverageNode_2                   matlab.ui.container.TreeNode
        UnknownNode_2                   matlab.ui.container.TreeNode
        UIAxes3                         matlab.ui.control.UIAxes
        BBNVisualisationTab             matlab.ui.container.Tab
        TradeoffsVisualisationTab       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Data % Description
    end
   
    methods (Access = private)
        
        function intervention_inf_plot(app)

            % create bbn structure
            Names = {'RCP'; 'Years'; 'Guided'; 'PrSites';'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';'CultES';'ProvES'};
            ParentCell = cell(1,12);
            % first 9 noces have no parents
            for i = 1:9
                ParentCell{i} = [];
            end
            % coral cover and 2 ES have all other nodes as parents
            % initially
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;
           
            data_bbn = table2array(app.Data);
            % get rid of algorithm indicator for now
            data_bbn(:,2) = [];
            
            % create bbn crank orrelation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            % second matrix for ploting links with non-zero corr

            yrs = 10:10:50;
            F = cell(1,5);
            if strcmp(app.MetricDropDown.Value,'Coral Cover')
                metric_ind = 1;
                axstr = 'Coral Cover'
            elseif strcmp(app.MetricDropDown.Value,'Cultural ES')
                metric_ind = 2;
                axstr = 'Cultural ES'
            elseif strcmp(app.MetricDropDown.Value,'Provisional ES')
                metric_ind = 3;
                axstr = 'Provisional ES'
            end
            
            if app.CButton.Value
                PrSites = 3;
            elseif app.AButton.Value
                PrSites = 1;
            else 
                PrSites = 2;
            end
            
            % perform inference on selected interventions and plot 
            % check whether histogram is selected or time series and plot
            % accordingly
            
            if strcmp(app.PlotDropDown.Value,'Histogram')
                hold(app.UIAxes,'on')              
                % for each year simulated
                for l = 1:length(yrs)
                    % retrieve histogram for select metric under selected 
                    % intervention scenario
                    F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,str2double(app.Seed1DropDown.Value),str2double(app.Seed2DropDown.Value),str2double(app.SRMDropDown.Value),str2double(app.AsAdptDropDown.Value),str2double(app.NatAdptDropDown.Value)],R,data_bbn,'full',1000,'near');
                    hist_dat = F{l};
                    % plot histogram of resulting distribution for metric (CC
                    % or ES)
                    histogram(app.UIAxes,hist_dat{metric_ind},'Normalization','probability','NumBins',50);
                end
                legend(app.UIAxes,'year 10','year 20','year 30','year 40','year 50','Interpreter','latex','FontSize',14);
                xlabel(app.UIAxes,axstr, 'Interpreter', 'latex','FontSize',14)
                ylabel(app.UIAxes, 'Probability','Interpreter','latex','FontSize',14)
                title(app.UIAxes,'Histogram with intervention','Interpreter','latex','FontSize',14)
                
                hold(app.UIAxes2,'on');               
                % repeat for non-intervention scenario
                for l = 1:length(yrs)
                    F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,0,0,0,0,0],R,data_bbn,'full',1000,'near');
                    hist_dat = F{l};
                    histogram(app.UIAxes2,hist_dat{metric_ind},'Normalization','probability','NumBins',50);
                end
                
                legend(app.UIAxes2,'year 10','year 20','year 30','year 40','year 50','Interpreter','latex','FontSize',14);
                xlabel(app.UIAxes2,axstr, 'Interpreter', 'latex','FontSize',14)
                ylabel(app.UIAxes2,'Probability','Interpreter','latex','FontSize',14)
                title(app.UIAxes2,'Histogram without intervention','Interpreter','latex','FontSize',14)    
            elseif strcmp(app.PlotDropDown.Value,'Violin Plot')
                ydata1 = [];
                ydata2 = [];
                % perform inferences for intervention and non-intervention
                for l = 1:length(yrs)
                    F = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,str2double(app.Seed1DropDown.Value),str2double(app.Seed2DropDown.Value),str2double(app.SRMDropDown.Value),str2double(app.AsAdptDropDown.Value),str2double(app.NatAdptDropDown.Value)],R,data_bbn,'full',1000,'near');
                    vi_dat = F{metric_ind}
                    ydata1 = [ydata1 vi_dat(:)];
                end
                
                for l = 1:length(yrs)
                    F = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,1,PrSites,0,0,0,0,0],R,data_bbn,'full',1000,'near');
                    vi_dat = F{metric_ind}
                    ydata2 = [ydata2 vi_dat(:)];
                end
                
                % plot violin plot for scenario with intervention
                hold(app.UIAxes,'on')
                [~, mu, sigma, ~, ~] = al_goodplot(ydata1,yrs,app.UIAxes,0.6,[0.5195,0.5977,0]);
                
                % print average value for each year on plot
                for k = 1:length(mu)
                    str{k} = sprintf('$\\mu$: %1.3f', mu(k));
                end
                text(app.UIAxes,yrs,mu,str,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',14)
                ylabel(app.UIAxes,axstr,'Interpreter','latex','FontSize',14)
                xlabel(app.UIAxes,'Years','FontSize',14)
                hold(app.UIAxes,'off')
                
                % plot violin plot for difference in intervention and no
                % intervention scenarios
                hold(app.UIAxes2,'on')
                [~, mu2, sigma2, ~, ~] = al_goodplot(ydata1-ydata2,yrs,app.UIAxes2,0.6);
                
                % print averages
                for k = 1:length(mu2)
                    str2{k} = sprintf('$\\mu$: %1.3f',mu2(k));
                end
                text(app.UIAxes2,yrs,mu2,str2,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',14)
                ylabel(app.UIAxes2,strcat('Difference in ',axstr),'Interpreter','latex','FontSize',14)
                xlabel(app.UIAxes2,'Years','FontSize',14)
                
                % adjust range and domain
                app.UIAxes.XLim = [5 60];
                app.UIAxes.YLim = [0 max(ydata1,[],'all')+0.01];
                app.UIAxes2.XLim = [5 60];
                app.UIAxes2.YLim = [0 max(ydata1-ydata2,[],'all')+0.01];
                
                hLeg=findobj(app.UIAxes2,'type','legend');
                set(hLeg,'visible','off')
            end
        end
        

    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Store the data in the app's properties
            app.Data = readtable('ADRIA_BBN_Data.csv');
        end

        % Button pushed function: MakeInterventionInferenceButton
        function MakeInterventionInferenceButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            intervention_inf_plot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterventionInferenceTab = uitab(app.TabGroup);
            app.InterventionInferenceTab.Title = 'Intervention Inference';
            app.UIAxes = uiaxes(app.InterventionInferenceTab);
            title(app.UIAxes, 'Counterfactual', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Time', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Metric', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.UIAxes2 = uiaxes(app.InterventionInferenceTab);
            title(app.UIAxes2, 'Intervention', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'Time', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'Metric', 'Interpreter', 'latex')
            zlabel(app.UIAxes2, 'Z', 'Interpreter', 'latex')
            app.InterventionSelectionPanel = uipanel(app.InterventionInferenceTab);
            app.InterventionSelectionPanel.Title = 'Intervention Selection';
            app.Seed1DropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.Seed1DropDownLabel.Text = 'Seed 1';
            app.Seed1DropDown = uidropdown(app.InterventionSelectionPanel);
            app.Seed1DropDown.Items = {'0', '0.0005', '0.001'};
            app.Seed1DropDown.Value = '0';
            app.Seed2DropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.Seed2DropDownLabel.Text = 'Seed 2';
            app.Seed2DropDown = uidropdown(app.InterventionSelectionPanel);
            app.Seed2DropDown.Items = {'0', '0.0005', '0.0001'};
            app.Seed2DropDown.Value = '0';
            app.SRMDropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.SRMDropDownLabel.Text = 'SRM';
            app.SRMDropDown = uidropdown(app.InterventionSelectionPanel);
            app.SRMDropDown.Items = {'0', '6', '12', ''};
            app.SRMDropDown.Value = '0';
            app.AsAdptDropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.AsAdptDropDownLabel.Text = 'As. Adpt. ';
            app.AsAdptDropDown = uidropdown(app.InterventionSelectionPanel);
            app.AsAdptDropDown.Items = {'0', '6', '12', ''};
            app.AsAdptDropDown.Value = '0';
            app.NatAdptDropDownLabel = uilabel(app.InterventionSelectionPanel);
            app.NatAdptDropDownLabel.Text = 'Nat. Adpt.';
            app.NatAdptDropDown = uidropdown(app.InterventionSelectionPanel);
            app.NatAdptDropDown.Items = {'0', '0.05', '0.1'};
            app.NatAdptDropDown.Value = '0';
            app.MakeInterventionInferenceButton = uibutton(app.InterventionSelectionPanel, 'push');
            app.MakeInterventionInferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MakeInterventionInferenceButtonPushed, true);
            app.MakeInterventionInferenceButton.Text = {'Make Intervention'; 'Inference'};
            app.SiteGroupButtonGroup = uibuttongroup(app.InterventionInferenceTab);
            app.SiteGroupButtonGroup.Title = 'Site Group';
            app.AButton = uiradiobutton(app.SiteGroupButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uiradiobutton(app.SiteGroupButtonGroup);
            app.BButton.Text = 'B';
            app.CButton = uiradiobutton(app.SiteGroupButtonGroup);
            app.CButton.Text = 'C';
            app.MCDAAlgorithmButtonGroup = uibuttongroup(app.InterventionInferenceTab);
            app.MCDAAlgorithmButtonGroup.Title = 'MCDA Algorithm';
            app.OrderRankingButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.OrderRankingButton.Text = 'Order Ranking';
            app.OrderRankingButton.Value = true;
            app.TOPSISButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.TOPSISButton.Text = 'TOPSIS';
            app.VIKORButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.VIKORButton.Text = 'VIKOR';
            app.RCPKnobLabel = uilabel(app.InterventionInferenceTab);
            app.RCPKnobLabel.Text = 'RCP';
            app.RCPKnob = uiknob(app.InterventionInferenceTab, 'discrete');
            app.RCPKnob.Items = {'2.6', '4.5', '6.0', '8.5'};
            app.RCPKnob.Value = '2.6';
            app.MetricDropDownLabel = uilabel(app.InterventionInferenceTab);
            app.MetricDropDownLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.InterventionInferenceTab);
            app.MetricDropDown.Items = {'Coral Cover', 'Cultural ES', 'Provisional ES'};
            app.MetricDropDown.Value = 'Coral Cover';
            app.PlotDropDownLabel = uilabel(app.InterventionInferenceTab);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.InterventionInferenceTab);
            app.PlotDropDown.Items = {'Histogram', 'Violin Plot'};
            app.PlotDropDown.Value = 'Histogram';
            app.MetricInferenceTab = uitab(app.TabGroup);
            app.MetricInferenceTab.Title = 'Metric Inference';
            app.UIAxes3 = uiaxes(app.MetricInferenceTab);
            xlabel(app.UIAxes3, 'Metric', 'Interpreter', 'latex')
            ylabel(app.UIAxes3, 'Probability', 'Interpreter', 'latex')
            zlabel(app.UIAxes3, 'Z', 'Interpreter', 'latex')
            app.InferenceparametersPanel = uipanel(app.MetricInferenceTab);
            app.InferenceparametersPanel.Title = 'Inference parameters';
            app.Tree = uitree(app.InferenceparametersPanel, 'checkbox');
            app.CoralCoverNode_2 = uitreenode(app.Tree);
            app.CoralCoverNode_2.Text = 'Coral Cover';
            app.ProbabilityNode_2 = uitreenode(app.CoralCoverNode_2);
            app.ProbabilityNode_2.Text = 'Probability';
            app.Node_4 = uitreenode(app.ProbabilityNode_2);
            app.Node_4.Text = '<';
            app.Node_3 = uitreenode(app.ProbabilityNode_2);
            app.Node_3.Text = '>';
            app.AverageNode_2 = uitreenode(app.CoralCoverNode_2);
            app.AverageNode_2.Text = 'Average';
            app.UnknownNode_2 = uitreenode(app.CoralCoverNode_2);
            app.UnknownNode_2.Text = 'Unknown';
            app.Tree2 = uitree(app.InferenceparametersPanel, 'checkbox');
            app.CulturalESNode = uitreenode(app.Tree2);
            app.CulturalESNode.Text = 'Cultural ES';
            app.ProbabilityNode_3 = uitreenode(app.CulturalESNode);
            app.ProbabilityNode_3.Text = 'Probability';
            app.Node_6 = uitreenode(app.ProbabilityNode_3);
            app.Node_6.Text = '<';
            app.Node_5 = uitreenode(app.ProbabilityNode_3);
            app.Node_5.Text = '>';
            app.AverageNode_3 = uitreenode(app.CulturalESNode);
            app.AverageNode_3.Text = 'Average';
            app.UnknownNode_3 = uitreenode(app.CulturalESNode);
            app.UnknownNode_3.Text = 'Unknown';
            app.Tree3 = uitree(app.InferenceparametersPanel, 'checkbox');
            app.ProvisionalESNode = uitreenode(app.Tree3);
            app.ProvisionalESNode.Text = 'Provisional ES';
            app.ProbabilityNode_4 = uitreenode(app.ProvisionalESNode);
            app.ProbabilityNode_4.Text = 'Probability';
            app.Node_2 = uitreenode(app.ProbabilityNode_4);
            app.Node_2.Text = '<';
            app.Node = uitreenode(app.ProbabilityNode_4);
            app.Node.Text = '>';
            app.AverageNode_4 = uitreenode(app.ProvisionalESNode);
            app.AverageNode_4.Text = 'Average';
            app.UnknownNode_4 = uitreenode(app.ProvisionalESNode);
            app.UnknownNode_4.Text = 'Unknown';
            app.ByYearDropDownLabel = uilabel(app.InferenceparametersPanel);
            app.ByYearDropDownLabel.Text = 'By Year';
            app.ByYearDropDown = uidropdown(app.InferenceparametersPanel);
            app.ByYearDropDown.Items = {'10', '20', '30', '40', '50'};
            app.ByYearDropDown.Value = '10';
            app.MakeMetricInferenceButton = uibutton(app.InferenceparametersPanel, 'push');
            app.MakeMetricInferenceButton.Text = 'Make Metric Inference';
            app.NoninterventionparametersPanel = uipanel(app.MetricInferenceTab);
            app.NoninterventionparametersPanel.Title = 'Non- intervention parameters';
            app.RCPKnob_2Label = uilabel(app.NoninterventionparametersPanel);
            app.RCPKnob_2Label.Text = 'RCP';
            app.RCPKnob_2 = uiknob(app.NoninterventionparametersPanel, 'discrete');
            app.RCPKnob_2.Items = {'2.6', '4.5', '6.0', '8.5'};
            app.RCPKnob_2.Value = '2.6';
            app.SiteSelectionButtonGroup = uibuttongroup(app.NoninterventionparametersPanel);
            app.SiteSelectionButtonGroup.Title = 'Site Selection';
            app.AButton_2 = uiradiobutton(app.SiteSelectionButtonGroup);
            app.AButton_2.Text = 'A';
            app.AButton_2.Value = true;
            app.BButton_2 = uiradiobutton(app.SiteSelectionButtonGroup);
            app.BButton_2.Text = 'B';
            app.CButton_2 = uiradiobutton(app.SiteSelectionButtonGroup);
            app.CButton_2.Text = 'C';
            app.MCDAAlgorithmButtonGroup_2 = uibuttongroup(app.NoninterventionparametersPanel);
            app.MCDAAlgorithmButtonGroup_2.Title = 'MCDA Algorithm';
            app.RankorderingButton = uiradiobutton(app.MCDAAlgorithmButtonGroup_2);
            app.RankorderingButton.Text = 'Rank ordering';
            app.RankorderingButton.Value = true;
            app.TOPSISButton_2 = uiradiobutton(app.MCDAAlgorithmButtonGroup_2);
            app.TOPSISButton_2.Text = 'TOPSIS';
            app.VIKORButton_2 = uiradiobutton(app.MCDAAlgorithmButtonGroup_2);
            app.VIKORButton_2.Text = 'VIKOR';
            app.InterventionlevelsPanel = uipanel(app.MetricInferenceTab);
            app.InterventionlevelsPanel.Title = 'Intervention levels';
            app.BBNVisualisationTab = uitab(app.TabGroup);
            app.BBNVisualisationTab.Title = 'BBN Visualisation';
            app.TradeoffsVisualisationTab = uitab(app.TabGroup);
            app.TradeoffsVisualisationTab.Title = 'Tradeoffs Visualisation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADRIA_BBN_app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end