classdef ADRIA_app_example_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADRIAOutputDisplayUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Panel2_6                       matlab.ui.container.Panel
        MCDADropDown                   matlab.ui.control.DropDown
        MCDADropDownLabel              matlab.ui.control.Label
        AnalysescenarioButton          matlab.ui.control.Button
        RCPKnob                        matlab.ui.control.DiscreteKnob
        RCPKnobLabel                   matlab.ui.control.Label
        Panel2_5                       matlab.ui.container.Panel
        NatAdtDropDown                 matlab.ui.control.DropDown
        NatAdtDropDownLabel            matlab.ui.control.Label
        AssAdtDropDown                 matlab.ui.control.DropDown
        AssAdtDropDownLabel            matlab.ui.control.Label
        ScenarioSelectionLabel         matlab.ui.control.Label
        Panel2_4                       matlab.ui.container.Panel
        SRMDropDown                    matlab.ui.control.DropDown
        SRMDropDownLabel               matlab.ui.control.Label
        Seed2DropDown                  matlab.ui.control.DropDown
        Seed2DropDownLabel             matlab.ui.control.Label
        Seed1DropDown                  matlab.ui.control.DropDown
        Seed1DropDownLabel             matlab.ui.control.Label
        Panel2_2                       matlab.ui.container.Panel
        BCheckBox                      matlab.ui.control.CheckBox
        ACheckBox                      matlab.ui.control.CheckBox
        CCheckBox_2                    matlab.ui.control.CheckBox
        Panel2                         matlab.ui.container.Panel
        YesCheckBox_2                  matlab.ui.control.CheckBox
        NoCheckBox_2                   matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        TimeSeriesTab                  matlab.ui.container.Tab
        MetricButtonGroup              matlab.ui.container.ButtonGroup
        ScopeforProvisionalESButton    matlab.ui.control.RadioButton
        ScopeforCulturalESButton       matlab.ui.control.RadioButton
        CoralCoverButton               matlab.ui.control.RadioButton
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        BBNTab                         matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        InterventionInferenceTab       matlab.ui.container.Tab
        WithoutInterventionLabel       matlab.ui.control.Label
        WithInterventionLabel          matlab.ui.control.Label
        TextArea_4                     matlab.ui.control.TextArea
        MetricButtonGroup_2            matlab.ui.container.ButtonGroup
        ScopeforProvisionalESButton_2  matlab.ui.control.RadioButton
        ScopeforCulturalESButton_2     matlab.ui.control.RadioButton
        CoralCoverButton_2             matlab.ui.control.RadioButton
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_3Label                matlab.ui.control.Label
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        MetricInferenceTab             matlab.ui.container.Tab
        TextArea_5                     matlab.ui.control.TextArea
        IfProbDropDown                 matlab.ui.control.DropDown
        IfProbDropDownLabel            matlab.ui.control.Label
        AvLabel                        matlab.ui.control.Label
        ProbLabel                      matlab.ui.control.Label
        ValueLabel                     matlab.ui.control.Label
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox                       matlab.ui.control.CheckBox
        AsAdptLamp                     matlab.ui.control.Lamp
        AsAdptLampLabel                matlab.ui.control.Label
        Gauge_7                        matlab.ui.control.LinearGauge
        Gauge4                         matlab.ui.control.LinearGauge
        Gauge3                         matlab.ui.control.LinearGauge
        Gauge2                         matlab.ui.control.LinearGauge
        Gauge_6                        matlab.ui.control.LinearGauge
        NatAdptLamp                    matlab.ui.control.Lamp
        NatAdptLampLabel               matlab.ui.control.Label
        SRMLamp                        matlab.ui.control.Lamp
        SRMLampLabel                   matlab.ui.control.Label
        Seed2Lamp                      matlab.ui.control.Lamp
        Seed2LampLabel                 matlab.ui.control.Label
        Seed1Lamp                      matlab.ui.control.Lamp
        Seed1LampLabel                 matlab.ui.control.Label
        ByYearDropDown                 matlab.ui.control.DropDown
        ByYearDropDownLabel            matlab.ui.control.Label
        Seed1GaugeLabel_3              matlab.ui.control.Label
        MakeinferenceButton            matlab.ui.control.Button
        ProvisionalESEditField         matlab.ui.control.EditField
        ProvisionalESEditFieldLabel    matlab.ui.control.Label
        CulturalESEditField            matlab.ui.control.EditField
        CulturalESEditFieldLabel       matlab.ui.control.Label
        CoralCoverEditField            matlab.ui.control.EditField
        CoralCoverEditFieldLabel       matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        DataTab                        matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the ADRIA_app_example_2 class.
        Data
        displayedIndices
        counterfIndices
    end
    
    methods (Access = private)
        
        function ScatterPlot(app)
            % function that plots scatter violin plots
            
            % Update X Llbel and declare x data
            app.UIAxes.XLabel.String = 'Years';
            xdata = 10:10:50;
            
            % select ydata based on values selected on interface
            if app.CoralCoverButton.Value
                ydata1 = reshape(app.Data.CoralCover(app.displayedIndices),10,length(app.Data.CoralCover(app.displayedIndices))/10);
                ydata2 = reshape(app.Data.CoralCover(app.counterfIndices),10,length(app.Data.CoralCover(app.counterfIndices))/10);
                axstr = 'Coral Cover';
            elseif app.ScopeforCulturalESButton.Value
                ydata1 = reshape(app.Data.Cult_ES(app.displayedIndices),10,length(app.Data.Cult_ES(app.displayedIndices))/10);
                ydata2 = reshape(app.Data.Cult_ES(app.counterfIndices),10,length(app.Data.Cult_ES(app.counterfIndices))/10);
                axstr = 'Cultural ES';
            elseif app.ScopeforProvisionalESButton.Value
                ydata1 = reshape(app.Data.Prov_ES(app.displayedIndices),10,length(app.Data.Prov_ES(app.displayedIndices))/10);
                ydata2 = reshape(app.Data.Prov_ES(app.counterfIndices),10,length(app.Data.Prov_ES(app.counterfIndices))/10); 
                axstr = 'Provisional ES';
            end
            
            str = cell(size(xdata));
            str2 = str;
            
            % plot violin plot for scenario with intervention
            hold(app.UIAxes,'on')
            [~, mu, sigma, ~, ~] = al_goodplot(ydata1,xdata,app.UIAxes,0.6,[0.5195,0.5977,0]);
            
            % print average value for each year on plot
            for k = 1:length(mu)
                str{k} = sprintf('$\\mu$: %1.3f', mu(k));
            end
            text(app.UIAxes,xdata,mu,str,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',14)
            ylabel(app.UIAxes,axstr,'Interpreter','latex','FontSize',14)
            xlabel(app.UIAxes,'Years','FontSize',14)
            hold(app.UIAxes,'off')
            
            % plot violin plot for difference in intervention and no
            % intervention scenarios
            hold(app.UIAxes_2,'on')
            [~, mu2, sigma2, ~, ~] = al_goodplot(ydata1-ydata2,xdata,app.UIAxes_2,0.6);
            
            % print averages
            for k = 1:length(mu2)
                str2{k} = sprintf('$\\mu$: %1.3f',mu2(k));
            end
            text(app.UIAxes_2,xdata,mu2,str2,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',14)
            ylabel(app.UIAxes_2,strcat('Difference in ',axstr),'Interpreter','latex','FontSize',14)
            xlabel(app.UIAxes_2,'Years','FontSize',14)
            % adjust range and domain
            hold(app.UIAxes,'on')
            app.UIAxes.XLim = [5 60];
            app.UIAxes.YLim = [0 max(ydata1,[],'all')+0.01];
            app.UIAxes_2.XLim = [5 60];
            app.UIAxes_2.YLim = [0 max(ydata1-ydata2,[],'all')+0.01];
        end       
        function BBNPlot(app)
            % function to plot BBN with rank correlation measures
            % and histograms for parameter inference
            
            % determine type of data to be plotted
            if app.CoralCoverButton_2.Value
                hist_ind = 1;
                axstr = 'Coral Cover'
            elseif app.ScopeforCulturalESButton_2.Value
                hist_ind = 2;  
                axstr = 'Cultural ES'
            elseif app.ScopeforProvisionalESButton_2.Value
                hist_ind = 3;
                axstr = 'Provisional ES'
            end
            
            % create bbn structure
            Names = {'RCP'; 'Years'; 'Guided'; 'PrSites';'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';'CultES';'ProvES'};
            ParentCell = cell(1,12);
            % first 9 noces have no parents
            for i = 1:9
                ParentCell{i} = [];
            end
            % coral cover and 2 ES have all other nodes as parents
            % initially
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;

            ParentCell2 = ParentCell;
           
            data_bbn = table2array(app.Data);
            % get rid of algorithm indicator for now
            data_bbn(:,2) = [];
            
            % create bbn crank orrelation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            % second matrix for ploting links with non-zero corr
            R2 = R;
            
            % find links with near zero and zero correlation and get rid of
            % them
            R2(abs(R2)<=10e-4) = NaN;
            R2(abs(R2)==0) = NaN;
            M = 1:9;
            for k = 1:9
                if isnan(R2(k,10))
                    M = M(M~=k);
                    ParentCell2{10} = M;
                end
                if isnan(R2(k,11))
                    M = M(M~=k);
                    ParentCell2{11} = M;
                end
                if isnan(R2(k,12))
                    M = M(M~=k);
                    ParentCell2{12} = M;
                end
            end
            
            % create BBN visualisation
            bn_visualize(ParentCell2,R2,Names,app.UIAxes3);
            str = '';
            str2 = '';
            yrs = 10:10:50;
            F = cell(1,5);
            
            % check if guided and sites selected
            if app.YesCheckBox_2.Value
                Guided = 1;
            else
                Guided = 0;
            end
            
            if app.CCheckBox_2.Value
                PrSites = 3;
            elseif app.ACheckBox.Value
                PrSites = 1;
            else app.BCheckBox.Value
                PrSites = 2;
            end
            
            % perform inference on selected interventions and plot 
            % inference histograms for intervention and
            % non-intervention scenarios
            hold(app.UIAxes2,'on');
            
            % for each year simulated
            for l = 1:length(yrs)
                % retrieve histogram for select metric under selected 
                % intervention scenario
                F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,Guided,PrSites,str2double(app.Seed1DropDown.Value),str2double(app.Seed2DropDown.Value),str2double(app.SRMDropDown.Value),str2double(app.AssAdtDropDown.Value),str2double(app.NatAdtDropDown.Value)],R,data_bbn,'full',1000,'near');
                hist_dat = F{l};
                % plot histogram of resulting distribution for metric (CC
                % or ES)
                h = histogram(app.UIAxes2,hist_dat{hist_ind},'Normalization','probability','NumBins',50);
                % print median and standard deviation for each year
                str = strcat(str,'\n',sprintf('Median (year %2.0f): %1.3f, Std (year %2.0f): %1.3f',yrs(l),median(hist_dat{1}),yrs(l),std(hist_dat{1})));
                str = sprintf(str);
            end
            legend(app.UIAxes2,'year 10','year 20','year 30','year 40','year 50','Interpreter','latex','FontSize',14);
            xlabel(app.UIAxes2,axstr, 'Interpreter', 'latex','FontSize',14)
            ylabel(app.UIAxes2, 'Probability','Interpreter','latex','FontSize',14)
            title(app.UIAxes2,'Histogram with intervention','Interpreter','latex','FontSize',14)
            hold(app.UIAxes2_2,'on');
            
            % repeat for non-intervention scenario
            for l = 1:length(yrs)
                F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,Guided,PrSites,0,0,0,0,0],R,data_bbn,'full',1000,'near');
                hist_dat = F{l};
                h = histogram(app.UIAxes2_2,hist_dat{hist_ind},'Normalization','probability','NumBins',50);
                str2 = strcat(str2,'\n',sprintf('Median (year %2.0f): %1.3f, Std (year %2.0f): %1.3f',yrs(l),median(hist_dat{1}),yrs(l),std(hist_dat{1})));                 
                str2 = sprintf(str2);
            end
            
            legend(app.UIAxes2_2,'year 10','year 20','year 30','year 40','year 50','Interpreter','latex','FontSize',14);
            xlabel(app.UIAxes2_2,axstr, 'Interpreter', 'latex','FontSize',14)
            ylabel(app.UIAxes2_2,'Probability','Interpreter','latex','FontSize',14)
            title(app.UIAxes2_2,'Histogram without intervention','Interpreter','latex','FontSize',14)
            % populate text box with stats for each hist
            app.TextArea_3.Value = str;
            app.TextArea_4.Value = str2;
        end
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the RCP control 
            tempIndices = [tempIndices, app.Data.RCP == str2double(app.RCPKnob.Value)*10];
            
            % Append a column to tempIndices to indicate data that satisfies MCDA control
            tempIndices = [tempIndices, app.Data.MCDAAlgs == str2double(app.MCDADropDown.Value)];
            
            % Append a column to tempIndices to indicate data that satisfies the Guided control 
            if app.YesCheckBox_2.Value && ~app.NoCheckBox_2.Value
                tempIndices = [tempIndices, app.Data.Guided == 1];
            elseif app.NoCheckBox_2.Value && ~app.YesCheckBox_2.Value
                tempIndices = [tempIndices, app.Data.Guided == 0];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the Site control
            if app.BCheckBox.Value && ~app.CCheckBox_2.Value && ~app.ACheckBox.Value
                tempIndices = [tempIndices, app.Data.PrSites == 2];
            elseif app.CCheckBox_2.Value && ~app.BCheckBox.Value && ~app.ACheckBox.Value
                tempIndices = [tempIndices, app.Data.PrSites == 3];
            elseif app.ACheckBox.Value && ~app.BCheckBox.Value && ~app.CCheckBox_2.Value
                tempIndices = [tempIndices, app.Data.PrSites == 1];
            end
            
            % counterfactual indexing will be the same except for
            % intervention indexing
            tempIndices2 = tempIndices;
            
            % Append a column to tempIndices to indicate data that satisfies the Seed1 control
            tempIndices = [tempIndices, app.Data.Seed1 == str2double(app.Seed1DropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.Seed1 == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Seed2 control
            tempIndices = [tempIndices, app.Data.Seed2 == str2double(app.Seed2DropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.Seed2 == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the SRM control
            tempIndices = [tempIndices, app.Data.SRM == str2double(app.SRMDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.SRM == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Assisted Adaptation control
            tempIndices = [tempIndices, app.Data.AssistedAdapt == str2double(app.AssAdtDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.AssistedAdapt == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Natural Adaptation control
            tempIndices = [tempIndices, app.Data.NaturalAdapt == str2double(app.NatAdtDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.NaturalAdapt == 0];
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
            app.counterfIndices = (sum(tempIndices2,2)/size(tempIndices2,2) == 1);
        end
        
        function inference_int(app)
            % function for bbn inference on the metrics (CC and ES)
            
            % retrieve parameters for inference
            % scenario
            
            if app.YesCheckBox_2.Value
                Guided = 1;
            else
                Guided = 0;
            end
            
            if app.CCheckBox_2.Value
                PrSites = 3;
            elseif app.ACheckBox.Value
                PrSites = 1;
            else app.BCheckBox.Value
                PrSites = 2;
            end
            % retreive min coral cover, cultural ES and prov, ES values
            CC = str2double(app.CoralCoverEditField.Value);
            C_ES = str2double(app.CulturalESEditField.Value);
            P_ES = str2double(app.ProvisionalESEditField.Value);
            year = str2double(app.ByYearDropDown.Value);
            
            % create bbn structure 
            Names = {'RCP'; 'Years'; 'Guided'; 'PrSites';'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';'CultES';'ProvES'};
            ParentCell = cell(1,12);
            for i = 1:9
                ParentCell{i} = [];
            end
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;

            ParentCell2 = ParentCell;
           
            data_bbn = table2array(app.Data);
            data_bbn(:,2) = [];
            
            % create bbn correlation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            
            % check for empty fields with non-empty checkboxes and empty
            % checkboxes with non-empty fields
            % if empty with checks, uncheck
            % if non-empty without checks or with 2 checks, add average
            % check as default
            if isnan(CC)
                if (app.CheckBox.Value == 1)||(app.CheckBox_4.Value == 1)
                    app.CheckBox_4.Value = 0;
                    app.CheckBox.Value = 0;
                end
            end
            if isnan(C_ES)
                 if (app.CheckBox_2.Value == 1)||(app.CheckBox_5.Value == 1)
                    app.CheckBox_2.Value = 0;
                    app.CheckBox_5.Value = 0;
                 end
            end
           if isnan(P_ES)
                 if (app.CheckBox_3.Value == 1)||(app.CheckBox_6.Value == 1)
                    app.CheckBox_3.Value = 0;
                    app.CheckBox_6.Value = 0;
                end
            end
           if ~isnan(CC)
                if ((app.CheckBox.Value == 0)&&(app.CheckBox_4.Value == 0))||((app.CheckBox.Value == 1)&&(app.CheckBox_4.Value == 1))
                    app.CheckBox_4.Value = 1;
                    app.CheckBox.Value = 0;
                end
           end
            if ~isnan(C_ES)
                 if ((app.CheckBox_2.Value == 0)&&(app.CheckBox_5.Value == 0))||((app.CheckBox_2.Value == 1)&&(app.CheckBox_5.Value == 1))
                    app.CheckBox_2.Value = 1;
                    app.CheckBox_5.Value = 0;

                 end
            end
           if ~isnan(P_ES)
                 if ((app.CheckBox_3.Value == 0)&&(app.CheckBox_6.Value == 0))||((app.CheckBox_3.Value == 1)&&(app.CheckBox_6.Value == 1))
                    app.CheckBox_3.Value = 1;
                    app.CheckBox_6.Value = 0;
                end
           end
            log_mat =[app.CheckBox.Value,app.CheckBox_4.Value;...
                      app.CheckBox_2.Value, app.CheckBox_5.Value;...
                      app.CheckBox_3.Value,app.CheckBox_6.Value];
            
            % check which boxes (if any) have been left blank, and perform
            % inference accordingly (this can probably be done much neater but will
            % do for now :'D )
            if (~isnan(CC))&&(~isnan(C_ES))&&(~isnan(P_ES))
                % determine how to treat each of the values and perform
                % calculation
                
                % if all averages, just get the mean intervention values
                if (log_mat(1,1) == 1)&&(log_mat(2,1) == 1)&&(log_mat(3,1) == 1)
                    F0 = inference([1:4 10:12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC,C_ES,P_ES],R,data_bbn,'mean',1000,'near');
                % if probability just on CC, get distribution for CC
                elseif (log_mat(1,2)==1)&&(log_mat(2,1) == 1)&&(log_mat(3,1) == 1)
                    G = inference([1:4 11:12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,C_ES,P_ES],R,data_bbn,'full',1000,'near');
                    F0 = zeros(5,1)
                    for j = 1:5
                        F0(j) = mean(G{j});
                    end
                    dist = G{6};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr = sum(dist(dist>CC))/sum(dist);
                        str = sprintf('Pr(CC>%1.2f) = %1.2f',CC,Pr);
                        app.TextArea_5.Value = str;
                    else
                          Pr = sum(dist(dist<CC))/sum(dist);
                        str = sprintf('Pr(CC<%1.2f) = %1.2f',CC,Pr);
                        app.TextArea_5.Value = str;
                    end
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability'); 
                    legend(app.UIAxes4,'$Pr(CC)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$CC$','Interpreter','latex')
                    % if probability just on CES, get distribution for CES
                    elseif (log_mat(2,2)==1)&&(log_mat(1,1) == 1)&&(log_mat(3,1) == 1)
                        G = inference([1:4 10,12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,CC,P_ES],R,data_bbn,'full',1000,'near');
                        F0 = zeros(6,1)
                        for j = 1:6
                            F0(j) = mean(G{j});
                        end
                        dist = G{6};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr = sum(dist(dist>C_ES))/sum(dist);
                            str = sprintf('Pr(C_ES>%1.2f) = %1.2f',C_ES,Pr);
                            app.TextArea_5.Value = str;
                        else
                              Pr = sum(dist(dist<C_ES))/sum(dist);
                            str = sprintf('Pr(C_ES<%1.2f) = %1.2f',C_ES,Pr);
                            app.TextArea_5.Value = str;
                        end
                      % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability'); 
                        legend(app.UIAxes4,'$Pr(C\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$C\_ES$','Interpreter','latex')
                    % if probability just on PES, get distribution for PES
                    elseif (log_mat(3,2)==1)&&(log_mat(1,1) == 1)&&(log_mat(2,1) == 1)
                        G = inference([1:4 10,11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,CC,C_ES],R,data_bbn,'full',1000,'near');
                        F0 = zeros(5,1)
                        for j = 1:5
                            F0(j) = mean(G{j});
                        end
                        dist = G{6};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr = sum(dist(dist>P_ES))/sum(dist);
                            str = sprintf('Pr(P_ES>%1.2f) = %1.2f',P_ES,Pr);
                            app.TextArea_5.Value = str;
                        else
                              Pr = sum(dist(dist<P_ES))/sum(dist);
                            str = sprintf('Pr(P_ES<%1.2f) = %1.2f',P_ES,Pr);
                            app.TextArea_5.Value = str;
                        end
                      % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability'); 
                        legend(app.UIAxes4,'$Pr(P\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$P\_ES$','Interpreter','latex')
                     % if probability on CC and CES, get distribution for
                     % both
                    elseif (log_mat(2,2)==1)&&(log_mat(1,2) == 1)&&(log_mat(3,1) == 1)
                        G = inference([1:4 12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,P_ES],R,data_bbn,'full',1000,'near');
                        F0 = zeros(7,1)
                        for j = 1:7
                            F0(j) = mean(G{j});
                        end
                        dist1 = G{6};
                        dist2 = G{7};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr1 = sum(dist1(dist1>CC))/sum(dist1);
                            Pr2 = sum(dist2(dist2>C_ES))/sum(dist2);
                            str = sprintf('Pr(CC>%1.2f) = %1.2f \n Pr(C_ES> %1.2f) = %1.2f',CC,Pr1,C_ES,Pr2);
                            app.TextArea_5.Value = str;
                        else
                            Pr1 = sum(dist1(dist1<CC))/sum(dist1);
                            Pr2 = sum(dist2(dist2<C_ES))/sum(dist2);
                            str = sprintf('Pr(CC<%1.2f) = %1.2f \n Pr(C_ES< %1.2f) = %1.2f',CC,Pr1,C_ES,Pr2);
                            app.TextArea_5.Value = str;
                        end
                      % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist1,'NumBins',30,'Normalization','Probability'); 
                        histogram(app.UIAxes4,dist2,'NumBins',30,'Normalization','Probability'); 
                        legend(app.UIAxes4,'$Pr(CC)$','$Pr(C\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$CC/C\_ES$','Interpreter','latex')
                     % if probability on CC and PES, get distribution for
                     % both
                    elseif (log_mat(3,2)==1)&&(log_mat(1,2) == 1)&&(log_mat(2,1) == 1)
                        G = inference([1:4 11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,C_ES],R,data_bbn,'full',1000,'near');
                        F0 = zeros(5,1)
                        for j = 1:5
                            F0(j) = mean(G{j});
                        end
                        dist1 = G{6};
                        dist2 = G{7};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr1 = sum(dist1(dist1>CC))/sum(dist1);
                            Pr2 = sum(dist2(dist2>P_ES))/sum(dist2);
                            str = sprintf('Pr(CC>%1.2f) = %1.2f \n Pr(P_ES> %1.2f) = %1.2f',CC,Pr1,P_ES,Pr2);
                            app.TextArea_5.Value = str;
                        else
                            Pr1 = sum(dist1(dist1<CC))/sum(dist1);
                            Pr2 = sum(dist2(dist2<P_ES))/sum(dist2);
                            str = sprintf('Pr(CC<%1.2f) = %1.2f \n Pr(P_ES< %1.2f) = %1.2f',CC,Pr1,P_ES,Pr2);
                            app.TextArea_5.Value = str;
                        end
                      % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist1,'NumBins',30,'Normalization','Probability'); 
                        histogram(app.UIAxes4,dist2,'NumBins',30,'Normalization','Probability'); 
                        legend(app.UIAxes4,'$Pr(CC)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$CC$','Interpreter','latex')
                    % if probability on CES and PES, get distribution for
                     % both
                    elseif (log_mat(3,2)==1)&&(log_mat(2,2) == 1)&&(log_mat(1,1) == 1)
                        G = inference([1:4 10],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,CC],R,data_bbn,'full',1000,'near');
                        F0 = zeros(5,1)
                        for j = 1:5
                            F0(j) = mean(G{j});
                        end
                        dist1 = G{6};
                        dist2 = G{7};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr1 = sum(dist1(dist1>C_ES))/sum(dist1);
                            Pr2 = sum(dist2(dist2>P_ES))/sum(dist2);
                            str = sprintf('Pr(C_ES>%1.2f) = %1.2f \n Pr(P_ES> %1.2f) = %1.2f',C_ES,Pr1,P_ES,Pr2);
                            app.TextArea_5.Value = str;
                        else
                            Pr1 = sum(dist1(dist1<C_ES))/sum(dist1);
                            Pr2 = sum(dist2(dist2<P_ES))/sum(dist2);
                            str = sprintf('Pr(C_ES<%1.2f) = %1.2f \n Pr(P_ES< %1.2f) = %1.2f',C_ES,Pr1,P_ES,Pr2);
                            app.TextArea_5.Value = str;
                        end
                        % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist1,'NumBins',30,'Normalization','Probability'); 
                        histogram(app.UIAxes4,dist2,'NumBins',30,'Normalization','Probability'); 
                        legend(app.UIAxes4,'$Pr(C\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$C\_ES$','Interpreter','latex')
                    % if probability on CC,CES and PES, get distribution for
                     % all
                    elseif (log_mat(3,2)==1)&&(log_mat(2,2) == 1)&&(log_mat(1,2) == 1)
                        G = inference([1:4],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites],R,data_bbn,'full',1000,'near');
                        F0 = zeros(5,1)
                        for j = 1:5
                            F0(j) = mean(G{j});
                        end
                        dist1 = G{6};
                        dist2 = G{7};
                        dist3 = G{8};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr1 = sum(dist1(dist1>CC))/sum(dist1);
                            Pr2 = sum(dist2(dist2>C_ES))/sum(dist2);
                            Pr3 = sum(dist3(dist3>P_ES))/sum(dist3);
                            str = sprintf('Pr(CC>%1.2f) = %1.2f \n Pr(C_ES> %1.2f) = %1.2f \n Pr(P_ES> %1.2f) = %1.2f',CC,Pr1,C_ES,Pr2,P_ES,Pr3);
                            app.TextArea_5.Value = str;
                        else
                            Pr1 = sum(dist1(dist1<CC))/sum(dist1);
                            Pr2 = sum(dist2(dist2<C_ES))/sum(dist2);
                            Pr3 = sum(dist3(dist3<P_ES))/sum(dist3);
                            str = sprintf('Pr(CC<%1.2f) = %1.2f \n Pr(C_ES< %1.2f) = %1.2f \n Pr(P_ES< %1.2f) = %1.2f',CC,Pr1,C_ES,Pr2,P_ES,Pr3);
                            app.TextArea_5.Value = str;
                        end
                        % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist1,'NumBins',30,'Normalization','Probability');
                        histogram(app.UIAxes4,dist2,'NumBins',30,'Normalization','Probability'); 
                        histogram(app.UIAxes4,dist3,'NumBins',30,'Normalization','Probability'); 
                        legend(app.UIAxes4,'$Pr(CC)$','$Pr(C\_ES)$','$Pr(P\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$CC/C\_ES/P\_ES$','Interpreter','latex')
                    end

            elseif isnan(CC)&&(~isnan(C_ES))&&(~isnan(P_ES))
                % if C_ES and P_ES are averages
                if (log_mat(3,1)==1)&&(log_mat(2,1) == 1)
                    F0 = inference([1:4 11,12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, C_ES,P_ES],R,data_bbn,'mean',1000,'near');
                    app.CoralCoverEditField.Value = num2str(F0(6));
                 % C_ES is a probability
                elseif (log_mat(3,1)==1)&&(log_mat(2,2) == 1)
                    G = inference([1:4 12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, P_ES],R,data_bbn,'full',1000,'near');
                    F0 = zeros(7,1)
                    for j = 1:7
                        F0(j) = mean(G{j});
                    end
                    dist = G{7};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr1 = sum(dist(dist>C_ES))/sum(dist);
                        str = sprintf('Pr(C_ES>%1.2f) = %1.2f ',C_ES,Pr1);
                        app.TextArea_5.Value = str;
                    else
                        Pr1 = sum(dist(dist<C_ES))/sum(dist);
                        str = sprintf('Pr(C_ES<%1.2f) = %1.2f',C_ES,Pr1);
                        app.TextArea_5.Value = str;
                    end
                    app.CoralCoverEditField.Value = num2str(F0(6));
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                    legend(app.UIAxes4,'$Pr(C\_ES)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$C\_ES$','Interpreter','latex')
                    % P_ES is a probability
                    elseif (log_mat(2,1)==1)&&(log_mat(3,2) == 1)
                        G = inference([1:4 11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, C_ES],R,data_bbn,'full',1000,'near');
                        F0 = zeros(7,1);
                        for j = 1:7
                            F0(j) = mean(G{j});
                        end
                        dist = G{7};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr1 = sum(dist(dist>P_ES))/sum(dist);
                            str = sprintf('Pr(P_ES>%1.2f) = %1.2f ',P_ES,Pr1);
                            app.TextArea_5.Value = str;
                        else
                            Pr1 = sum(dist(dist<P_ES))/sum(dist);
                            str = sprintf('Pr(P_ES<%1.2f) = %1.2f',P_ES,Pr1);
                            app.TextArea_5.Value = str;
                        end
                        app.CoralCoverEditField.Value = num2str(F0(6));
                        % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                        legend(app.UIAxes4,'$Pr(P\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$P\_ES$','Interpreter','latex')
                     % P_ES is prob, C_ES is prob
                    elseif (log_mat(2,2)==1)&&(log_mat(3,2) == 1)
                        G = inference([1:4],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites],R,data_bbn,'full',1000,'near');
                        F0 = zeros(8,1);
                        for j = 1:8
                            F0(j) = mean(G{j});
                        end
                        dist1 = G{7};
                        dist2 = G{8};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr1 = sum(dist1(dist1>C_ES))/sum(dist1);
                            Pr2 = sum(dist2(dist2>P_ES))/sum(dist2);
                            str = sprintf('Pr(C_ES>%1.2f) = %1.2f \n Pr(P_ES> %1.2f) = %1.2f',C_ES,Pr1,P_ES,Pr2);
                            app.TextArea_5.Value = str;
                        else
                            Pr1 = sum(dist1(dist1<C_ES))/sum(dist1);
                            Pr2 = sum(dist2(dist2<P_ES))/sum(dist2);
                            str = sprintf('Pr(C_ES<%1.2f) = %1.2f \n Pr(P_ES< %1.2f) = %1.2f',C_ES,Pr1,P_ES,Pr2);
                            app.TextArea_5.Value = str;
                        end
                        app.CoralCoverEditField.Value = num2str(F0(6));
                        % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist1,'NumBins',30,'Normalization','Probability');
                        histogram(app.UIAxes4,dist2,'NumBins',30,'Normalization','Probability');
                        legend(app.UIAxes4,'$Pr(C\_ES)$','$Pr(P\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$C\_ES/P\_ES$','Interpreter','latex')
                end
            elseif isnan(C_ES)&&(~isnan(CC))&&(~isnan(P_ES))
                % CC and P_ES are both averages
                if (log_mat(1,1)==1)&&(log_mat(3,1) == 1)
                    F0 = inference([1:4 10,12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC,P_ES],R,data_bbn,'mean',1000,'near');
                    app.CulturalESEditField.Value = num2str(F0(6));
                % CC is a prob, P_ES is an average
                elseif (log_mat(1,2)==1)&&(log_mat(3,1) == 1)
                        G = inference([1:4 12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, P_ES],R,data_bbn,'full',1000,'near');
                        F0 = zeros(7,1);
                        for j = 1:7
                            F0(j) = mean(G{j});
                        end
                        dist = G{6};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr = sum(dist(dist>CC))/sum(dist);
                            str = sprintf('Pr(CC>%1.2f) = %1.2f ',CC,Pr);
                            app.TextArea_5.Value = str;
                        else
                            Pr = sum(dist(dist<CC))/sum(dist);
                            str = sprintf('Pr(CC<%1.2f) = %1.2f ',CC,Pr);
                            app.TextArea_5.Value = str;
                        end
                        app.CulturalESEditField.Value = num2str(F0(7));
                        
                        % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                        legend(app.UIAxes4,'$Pr(CC)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$CC$','Interpreter','latex')
                 % CC is an av., P_ES is a prob.
                elseif (log_mat(1,1)==1)&&(log_mat(3,2) == 1)
                        G = inference([1:4 10],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC],R,data_bbn,'full',1000,'near');
                        F0 = zeros(7,1);
                        for j = 1:7
                            F0(j) = mean(G{j});
                        end
                        dist = G{7};
                        % calculate probability given > or less than inputs
                        if strcmp(app.IfProbDropDown.Value,'>')
                            Pr = sum(dist(dist>P_ES))/sum(dist);
                            str = sprintf('Pr(P_ES>%1.2f) = %1.2f ',P_ES,Pr);
                            app.TextArea_5.Value = str;
                        else
                            Pr = sum(dist(dist<P_ES))/sum(dist);
                            str = sprintf('Pr(P_ES<%1.2f) = %1.2f ',P_ES,Pr);
                            app.TextArea_5.Value = str;
                        end
                        app.CulturalESEditField.Value = num2str(F0(6));
                        % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                        legend(app.UIAxes4,'$Pr(P\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$P\_ES$','Interpreter','latex')
                 % CC is a prob., P_ES is a prob.
                elseif (log_mat(1,2)==1)&&(log_mat(3,2) == 1)
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites],R,data_bbn,'full',1000,'near');
                    F0 = zeros(8,1);
                    for j = 1:8
                        F0(j) = mean(G{j});
                    end
                    dist1 = G{6};
                    dist2 = G{8};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr1 = sum(dist1(dist1>CC))/sum(dist1);
                        Pr2 = sum(dist2(dist2>P_ES))/sum(dist2);
                        str = sprintf('Pr(CC>%1.2f) = %1.2f \n Pr(P_ES> %1.2f) = %1.2f ',CC,Pr1,P_ES,Pr2);
                        app.TextArea_5.Value = str;
                    else
                        Pr1 = sum(dist1(dist1<CC))/sum(dist1);
                        Pr2 = sum(dist2(dist2<P_ES))/sum(dist2);
                        str = sprintf('Pr(CC<%1.2f) = %1.2f \n Pr(P_ES< %1.2f) = %1.2f ',CC,Pr1,P_ES,Pr2);
                        app.TextArea_5.Value = str;
                    end
                    app.CulturalESEditField.Value = num2str(F0(7));
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist1,'NumBins',30,'Normalization','Probability');
                    histogram(app.UIAxes4,dist2,'NumBins',30,'Normalization','Probability');
                    legend(app.UIAxes4,'$Pr(CC)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$CC$','Interpreter','latex')
                end
                    
            elseif isnan(P_ES)&&(~isnan(CC))&&(~isnan(C_ES))
                % CC and C_ES are averages
                if (log_mat(1,1)==1)&&(log_mat(2,1) == 1)
                    F0 = inference([1:4 10,11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC,C_ES],R,data_bbn,'mean',1000,'near'); 
                    app.ProvisionalESEditField.Value = num2str(F0(6));
                % CC is a prob and C_ES is an av.
                elseif (log_mat(2,1)==1)&&(log_mat(1,2) == 1)
                    G = inference([1:4 11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, C_ES],R,data_bbn,'full',1000,'near'); 
                    F0 = zeros(7,1);
                    for j = 1:7
                        F0(j) = mean(G{j});
                    end
                    dist = G{6};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr = sum(dist(dist>CC))/sum(dist);
                        str = sprintf('Pr(CC>%1.2f) = %1.2f',CC,Pr);
                        app.TextArea_5.Value = str;
                    else
                        Pr = sum(dist(dist<CC))/sum(dist);
                        str = sprintf('Pr(CC<%1.2f) = %1.2f',CC,Pr);
                        app.TextArea_5.Value = str;
                    end
                    app.ProvisionalESEditField.Value = num2str(F0(7));
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                    legend(app.UIAxes4,'$Pr(CC)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$CC$','Interpreter','latex')
                % CC is an av. and C_ES is an prob.
                elseif (log_mat(1,1)==1)&&(log_mat(2,2) == 1)
                    G = inference([1:4 10],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC],R,data_bbn,'full',1000,'near'); 
                    F0 = zeros(7,1);
                    for j = 1:7
                        F0(j) = mean(G{j});
                    end
                    dist = G{6};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr = sum(dist(dist>C_ES))/sum(dist);
                        str = sprintf('Pr(C_ES>%1.2f) = %1.2f',C_ES,Pr);
                        app.TextArea_5.Value = str;
                    else
                        Pr = sum(dist(dist<C_ES))/sum(dist);
                        str = sprintf('Pr(C_ES<%1.2f) = %1.2f',C_ES,Pr);
                        app.TextArea_5.Value = str;
                    end
                    app.ProvisionalESEditField.Value = num2str(F0(7));
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                    legend(app.UIAxes4,'$Pr(C\_ES)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$C\_ES$','Interpreter','latex')
                 % both are prob.
                elseif (log_mat(1,2)==1)&&(log_mat(2,2) == 1)
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites],R,data_bbn,'full',1000,'near'); 
                    F0 = zeros(8,1)
                    for j = 1:8
                        F0(j) = mean(G{j});
                    end
                    dist1 = G{6};
                    dist2 = G{7};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr1 = sum(dist1(dist1>CC))/sum(dist1);
                        Pr2 = sum(dist2(dist2>C_ES))/sum(dist2);
                        str = sprintf('Pr(CC>%1.2f) = %1.2f \n Pr(C_ES> %1.2f) = %1.2f',CC,Pr1,C_ES,Pr2);
                        app.TextArea_5.Value = str;
                    else
                        Pr1 = sum(dist1(dist1<CC))/sum(dist1);
                        Pr2 = sum(dist2(dist2<C_ES))/sum(dist2);
                        str = sprintf('Pr(CC< %1.2f) = %1.2f \n Pr(C_ES< %1.2f) = %1.2f',CC,Pr1,C_ES,Pr2);
                        app.TextArea_5.Value = str;
                    end
                    app.ProvisionalESEditField.Value = num2str(F0(8));
                                            % plot histograms of probability
                        hold(app.UIAxes4,'on')
                        histogram(app.UIAxes4,dist1,'NumBins',30,'Normalization','Probability');
                        histogram(app.UIAxes4,dist2,'NumBins',30,'Normalization','Probability');
                        legend(app.UIAxes4,'$Pr(CC)$','$Pr(C\_ES)$','Interpreter','latex');
                        ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                        xlabel(app.UIAxes4,'$CC/C\_ES$','Interpreter','latex')
                end
                
            elseif (isnan(CC))&&(~isnan(C_ES))&&(isnan(P_ES))
                if (log_mat(2,1)==1)
                    F0 = inference([1:4 11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,C_ES],R,data_bbn,'mean',1000,'near');
                    app.CoralCoverEditField.Value = num2str(F0(6));
                    app.ProvisionalESEditField.Value = num2str(F0(7));
                elseif (log_mat(2,2)==1)
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites],R,data_bbn,'full',1000,'near'); 
                    F0 = zeros(8,1)
                    for j = 1:8
                        F0(j) = mean(G{j});
                    end
                    dist = G{7};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr = sum(dist(dist>C_ES))/sum(dist);
                        str = sprintf('Pr(C_ES>%1.2f) = %1.2f',C_ES,Pr);
                        app.TextArea_5.Value = str;
                    else
                        Pr = sum(dist(dist<C_ES))/sum(dist);
                        str = sprintf('Pr(C_ES< %1.2f) = %1.2f',C_ES,Pr);
                        app.TextArea_5.Value = str;
                    end
                    app.CoralCoverEditField.Value = num2str(F0(6));
                    app.ProvisionalESEditField.Value = num2str(F0(8));
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                    legend(app.UIAxes4,'$Pr(C\_ES)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$C\_ES$','Interpreter','latex')
                end
            elseif (~isnan(CC))&&(isnan(C_ES))&&(isnan(P_ES))
                if (log_mat(1,1)==1)
                    F0 = inference([1:4 10],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,CC],R,data_bbn,'mean',1000,'near');
                    app.ProvisionalESEditField.Value = num2str(F0(7));
                    app.CulturalESEditField.Value = num2str(F0(6));
                elseif (log_mat(1,2)==1)
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites],R,data_bbn,'full',1000,'near'); 
                    F0 = zeros(8,1)
                    for j = 1:8
                        F0(j) = mean(G{j});
                    end
                    dist = G{6};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr = sum(dist(dist>CC))/sum(dist);
                        str = sprintf('Pr(CC>%1.2f) = %1.2f',CC,Pr);
                        app.TextArea_5.Value = str;
                    else
                        Pr = sum(dist(dist<CC))/sum(dist);
                        str = sprintf('Pr(CC< %1.2f) = %1.2f',CC,Pr);
                        app.TextArea_5.Value = str;
                    end
                    app.ProvisionalESEditField.Value = num2str(F0(8));
                    app.CulturalESEditField.Value = num2str(F0(7));
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                    legend(app.UIAxes4,'$Pr(CC)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$CC$','Interpreter','latex')
                end

            elseif (isnan(CC))&&(isnan(C_ES))&&(~isnan(P_ES))

                if (log_mat(3,1)==1)
                    F0 = inference([1:4 12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,P_ES],R,data_bbn,'mean',1000,'near');  
                    app.CoralCoverEditField.Value = num2str(F0(6));
                    app.CulturalESEditField.Value = num2str(F0(7));
                elseif (log_mat(3,2)==1)
                    G = inference([1:4],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites],R,data_bbn,'full',1000,'near'); 
                    F0 = zeros(8,1)
                    for j = 1:8
                        F0(j) = mean(G{j});
                    end
                    dist = G{8};
                    % calculate probability given > or less than inputs
                    if strcmp(app.IfProbDropDown.Value,'>')
                        Pr = sum(dist(dist>P_ES))/sum(dist);
                        str = sprintf('Pr(P_ES> %1.2f) = %1.2f',P_ES,Pr);
                        app.TextArea_5.Value = str;
                    else
                        Pr = sum(dist(dist<P_ES))/sum(dist);
                        str = sprintf('Pr(P_ES< %1.2f) = %1.2f',P_ES,Pr);
                        app.TextArea_5.Value = str;
                    end
                    app.CoralCoverEditField.Value = num2str(F0(6));
                    app.CulturalESEditField.Value = num2str(F0(7));
                    % plot histograms of probability
                    hold(app.UIAxes4,'on')
                    histogram(app.UIAxes4,dist,'NumBins',30,'Normalization','Probability');
                    legend(app.UIAxes4,'$Pr(P\_ES)$','Interpreter','latex');
                    ylabel(app.UIAxes4,'Probability','Interpreter','latex')
                    xlabel(app.UIAxes4,'$P\_ES$','Interpreter','latex')
                end
            end
            
            % retrieve inference values for parameters
            F0(F0<0) = 0;
            F0 = round(F0,4);
            app.Gauge_6.Value = F0(1);
            app.Gauge2.Value = F0(2);
            app.Gauge3.Value = F0(3);
            app.Gauge4.Value = F0(5);
            app.Gauge_7.Value = F0(4);
            
            % Seed1 light colour
            if app.Gauge_6.Value == 0
                % if zero, light is red
                app.Seed1Lamp.Color = [1,69/255,0];
            elseif (app.Gauge_6.Value>0)&&(app.Gauge_6.Value<=(0.25*app.Gauge_6.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.Seed1Lamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.Seed1Lamp.Color = [50/255,205/255,50/255];
            end
            
                        
            % Seed2 light colour
            if app.Gauge2.Value == 0
                % if zero, light is red
                app.Seed2Lamp.Color = [1,69/255,0];
            elseif (app.Gauge2.Value>0)&&(app.Gauge2.Value<=(0.25*app.Gauge2.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.Seed2Lamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.Seed2Lamp.Color = [50/255,205/255,50/255];
            end
            
                        % SRM light colour
            if app.Gauge3.Value == 0
                % if zero, light is red
                app.SRMLamp.Color = [1,69/255,0];
            elseif (app.Gauge3.Value>0)&&(app.Gauge3.Value<=(0.25*app.Gauge3.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.SRMLamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.SRMLamp.Color = [50/255,205/255,50/255];
            end
            
            % Seed2 light colour
            if app.Gauge4.Value == 0
                % if zero, light is red
                app.NatAdptLamp.Color = [1,69/255,0];
            elseif (app.Gauge4.Value>0)&&(app.Gauge4.Value<=(0.25*app.Gauge4.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.NatAdptLamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.NatAdptLamp.Color = [50/255,205/255,50/255];
            end
            
             if app.Gauge_7.Value == 0
                % if zero, light is red
                app.AsAdptLamp.Color = [1,69/255,0];
             elseif (app.Gauge_7.Value>0)&&(app.Gauge_7.Value<=(0.25*app.Gauge_7.Limits(2)))
                % if less than a quarter of max on gague, set as orange
                app.AsAdptLamp.Color = [1,215/255,0];
            else
                % otherwise, set as green
                app.AsAdptLamp.Color = [50/255,205/255,50/255];
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %adjust sizings to fit screen pixels
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.6;
            height = screenHeight*0.6;
            drawnow;
            app.ADRIAOutputDisplayUIFigure.Position = [left bottom width height];
            
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = readtable('ADRIA_BBN_Data.csv');
            app.UITable.Data = app.Data;
        end

        % Button pushed function: AnalysescenarioButton
        function AnalysescenarioButtonPushed(app, event)
          
            % Start with a fresh plot
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes_2)
            cla(app.UIAxes2_2)
            hold(app.UIAxes,'on')
            
            % Filter the data according to the controls
            filterData(app);
            ScatterPlot(app);
            BBNPlot(app);
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Button pushed function: MakeinferenceButton
        function MakeinferenceButtonPushed(app, event)
            cla(app.UIAxes4)
            inference_int(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADRIAOutputDisplayUIFigure = uifigure('Visible', 'off');
            app.ADRIAOutputDisplayUIFigure.Name = 'ADRIA Output Display';
            app.GridLayout = uigridlayout(app.ADRIAOutputDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2 = uipanel(app.LeftPanel);
            app.Panel2.Title = 'Guided';
            app.NoCheckBox_2 = uicheckbox(app.Panel2);
            app.NoCheckBox_2.Text = 'No';
            app.YesCheckBox_2 = uicheckbox(app.Panel2);
            app.YesCheckBox_2.Text = 'Yes';
            app.YesCheckBox_2.Value = true;
            app.Panel2_2 = uipanel(app.LeftPanel);
            app.Panel2_2.Title = 'Sites';
            app.CCheckBox_2 = uicheckbox(app.Panel2_2);
            app.CCheckBox_2.Text = 'C';
            app.CCheckBox_2.Value = true;
            app.ACheckBox = uicheckbox(app.Panel2_2);
            app.ACheckBox.Text = 'A';
            app.BCheckBox = uicheckbox(app.Panel2_2);
            app.BCheckBox.Text = 'B';
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Seeding/SRM';
            app.Seed1DropDownLabel = uilabel(app.Panel2_4);
            app.Seed1DropDownLabel.Text = 'Seed 1';
            app.Seed1DropDown = uidropdown(app.Panel2_4);
            app.Seed1DropDown.Items = {'0', '0.005', '0.001'};
            app.Seed1DropDown.Value = '0';
            app.Seed2DropDownLabel = uilabel(app.Panel2_4);
            app.Seed2DropDownLabel.Text = 'Seed 2';
            app.Seed2DropDown = uidropdown(app.Panel2_4);
            app.Seed2DropDown.Items = {'0', '0.0005', '0.001'};
            app.Seed2DropDown.Value = '0';
            app.SRMDropDownLabel = uilabel(app.Panel2_4);
            app.SRMDropDownLabel.Text = 'SRM';
            app.SRMDropDown = uidropdown(app.Panel2_4);
            app.SRMDropDown.Items = {'0', '6', '12'};
            app.SRMDropDown.Value = '0';
            app.ScenarioSelectionLabel = uilabel(app.LeftPanel);
            app.ScenarioSelectionLabel.Text = 'Scenario Selection';
            app.Panel2_5 = uipanel(app.LeftPanel);
            app.Panel2_5.Title = 'Ass./Nat. Adapt.';
            app.AssAdtDropDownLabel = uilabel(app.Panel2_5);
            app.AssAdtDropDownLabel.Text = 'Ass. Adt.';
            app.AssAdtDropDown = uidropdown(app.Panel2_5);
            app.AssAdtDropDown.Items = {'0', '6', '12', ''};
            app.AssAdtDropDown.Value = '0';
            app.NatAdtDropDownLabel = uilabel(app.Panel2_5);
            app.NatAdtDropDownLabel.Text = 'Nat. Adt.';
            app.NatAdtDropDown = uidropdown(app.Panel2_5);
            app.NatAdtDropDown.Items = {'0', '0.05', '0.1'};
            app.NatAdtDropDown.Value = '0';
            app.RCPKnobLabel = uilabel(app.LeftPanel);
            app.RCPKnobLabel.Text = 'RCP';
            app.RCPKnob = uiknob(app.LeftPanel, 'discrete');
            app.RCPKnob.Items = {'2.6', '4.5', '6.0', '8.5'};
            app.RCPKnob.Value = '2.6';
            app.AnalysescenarioButton = uibutton(app.LeftPanel, 'push');
            app.AnalysescenarioButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysescenarioButtonPushed, true);
            app.AnalysescenarioButton.Text = 'Analyse scenario';
            app.Panel2_6 = uipanel(app.LeftPanel);
            app.Panel2_6.Title = 'Ass./Nat. Adapt.';
            app.MCDADropDownLabel = uilabel(app.Panel2_6);
            app.MCDADropDownLabel.Text = 'MCDA';
            app.MCDADropDown = uidropdown(app.Panel2_6);
            app.MCDADropDown.Items = {'1', '2', '3'};
            app.MCDADropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TimeSeriesTab = uitab(app.TabGroup);
            app.TimeSeriesTab.Title = 'Time Series';
            app.UIAxes = uiaxes(app.TimeSeriesTab);
            title(app.UIAxes, 'With Intervention', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Years', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Coral Cover/ES ', 'Interpreter', 'latex')
            app.UIAxes_2 = uiaxes(app.TimeSeriesTab);
            title(app.UIAxes_2, 'Intervention-counterfactural', 'Interpreter', 'latex')
            xlabel(app.UIAxes_2, 'Years', 'Interpreter', 'latex')
            ylabel(app.UIAxes_2, 'Difference', 'Interpreter', 'latex')
            app.MetricButtonGroup = uibuttongroup(app.TimeSeriesTab);
            app.MetricButtonGroup.Title = 'Metric';
            app.CoralCoverButton = uiradiobutton(app.MetricButtonGroup);
            app.CoralCoverButton.Text = 'Coral Cover';
            app.CoralCoverButton.Value = true;
            app.ScopeforCulturalESButton = uiradiobutton(app.MetricButtonGroup);
            app.ScopeforCulturalESButton.Text = 'Scope for Cultural ES ';
            app.ScopeforProvisionalESButton = uiradiobutton(app.MetricButtonGroup);
            app.ScopeforProvisionalESButton.Text = 'Scope for Provisional ES';
            app.BBNTab = uitab(app.TabGroup);
            app.BBNTab.Title = 'BBN';
            app.UIAxes3 = uiaxes(app.BBNTab);
            title(app.UIAxes3, 'BBN Rank Correlations', 'Interpreter', 'latex')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.InterventionInferenceTab = uitab(app.TabGroup);
            app.InterventionInferenceTab.Title = 'Intervention Inference';
            app.UIAxes2 = uiaxes(app.InterventionInferenceTab);
            xlabel(app.UIAxes2, 'Coral Cover/ES', 'Interpreter', 'latex')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes2_2 = uiaxes(app.InterventionInferenceTab);
            xlabel(app.UIAxes2_2, 'Coral Cover/ES', 'Interpreter', 'latex')
            app.UIAxes2_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2_2.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.TextArea_3Label = uilabel(app.InterventionInferenceTab);
            app.TextArea_3Label.Text = '';
            app.TextArea_3 = uitextarea(app.InterventionInferenceTab);
            app.MetricButtonGroup_2 = uibuttongroup(app.InterventionInferenceTab);
            app.MetricButtonGroup_2.Title = 'Metric';
            app.CoralCoverButton_2 = uiradiobutton(app.MetricButtonGroup_2);
            app.CoralCoverButton_2.Text = 'Coral Cover';
            app.CoralCoverButton_2.Value = true;
            app.ScopeforCulturalESButton_2 = uiradiobutton(app.MetricButtonGroup_2);
            app.ScopeforCulturalESButton_2.Text = 'Scope for Cultural ES ';
            app.ScopeforProvisionalESButton_2 = uiradiobutton(app.MetricButtonGroup_2);
            app.ScopeforProvisionalESButton_2.Text = 'Scope for Provisional ES';
            app.TextArea_4 = uitextarea(app.InterventionInferenceTab);
            app.WithInterventionLabel = uilabel(app.InterventionInferenceTab);
            app.WithInterventionLabel.Text = 'With Intervention';
            app.WithoutInterventionLabel = uilabel(app.InterventionInferenceTab);
            app.WithoutInterventionLabel.Text = 'Without Intervention';
            app.MetricInferenceTab = uitab(app.TabGroup);
            app.MetricInferenceTab.Title = 'Metric Inference';
            app.UIAxes4 = uiaxes(app.MetricInferenceTab);
            xlabel(app.UIAxes4, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes4, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes4, 'Z', 'Interpreter', 'latex')
            app.CoralCoverEditFieldLabel = uilabel(app.MetricInferenceTab);
            app.CoralCoverEditFieldLabel.Text = 'Coral Cover';
            app.CoralCoverEditField = uieditfield(app.MetricInferenceTab, 'text');
            app.CulturalESEditFieldLabel = uilabel(app.MetricInferenceTab);
            app.CulturalESEditFieldLabel.Text = 'Cultural ES ';
            app.CulturalESEditField = uieditfield(app.MetricInferenceTab, 'text');
            app.ProvisionalESEditFieldLabel = uilabel(app.MetricInferenceTab);
            app.ProvisionalESEditFieldLabel.Text = 'Provisional ES ';
            app.ProvisionalESEditField = uieditfield(app.MetricInferenceTab, 'text');
            app.MakeinferenceButton = uibutton(app.MetricInferenceTab, 'push');
            app.MakeinferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MakeinferenceButtonPushed, true);
            app.MakeinferenceButton.Text = 'Make inference';
            app.Seed1GaugeLabel_3 = uilabel(app.MetricInferenceTab);
            app.Seed1GaugeLabel_3.Text = '';
            app.ByYearDropDownLabel = uilabel(app.MetricInferenceTab);
            app.ByYearDropDownLabel.Text = 'By Year';
            app.ByYearDropDown = uidropdown(app.MetricInferenceTab);
            app.ByYearDropDown.Items = {'10', '20', '30', '40', '50'};
            app.ByYearDropDown.Value = '10';
            app.Seed1LampLabel = uilabel(app.MetricInferenceTab);
            app.Seed1LampLabel.Text = 'Seed 1';
            app.Seed1Lamp = uilamp(app.MetricInferenceTab);
            app.Seed2LampLabel = uilabel(app.MetricInferenceTab);
            app.Seed2LampLabel.Text = 'Seed 2';
            app.Seed2Lamp = uilamp(app.MetricInferenceTab);
            app.SRMLampLabel = uilabel(app.MetricInferenceTab);
            app.SRMLampLabel.Text = 'SRM';
            app.SRMLamp = uilamp(app.MetricInferenceTab);
            app.NatAdptLampLabel = uilabel(app.MetricInferenceTab);
            app.NatAdptLampLabel.Text = 'Nat. Adpt.';
            app.NatAdptLamp = uilamp(app.MetricInferenceTab);
            app.Gauge_6 = uigauge(app.MetricInferenceTab, 'linear');
            app.Gauge_6.Limits = [0 0.001];
            app.Gauge2 = uigauge(app.MetricInferenceTab, 'linear');
            app.Gauge2.Limits = [0 0.001];
            app.Gauge3 = uigauge(app.MetricInferenceTab, 'linear');
            app.Gauge3.Limits = [0 12];
            app.Gauge4 = uigauge(app.MetricInferenceTab, 'linear');
            app.Gauge4.Limits = [0 0.1];
            app.Gauge_7 = uigauge(app.MetricInferenceTab, 'linear');
            app.Gauge_7.Limits = [0 12];
            app.AsAdptLampLabel = uilabel(app.MetricInferenceTab);
            app.AsAdptLampLabel.Text = 'As. Adpt.';
            app.AsAdptLamp = uilamp(app.MetricInferenceTab);
            app.CheckBox = uicheckbox(app.MetricInferenceTab);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.MetricInferenceTab);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.MetricInferenceTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.MetricInferenceTab);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.MetricInferenceTab);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.MetricInferenceTab);
            app.CheckBox_6.Text = '';
            app.ValueLabel = uilabel(app.MetricInferenceTab);
            app.ValueLabel.Text = 'Value';
            app.ProbLabel = uilabel(app.MetricInferenceTab);
            app.ProbLabel.Text = 'Prob.';
            app.AvLabel = uilabel(app.MetricInferenceTab);
            app.AvLabel.Text = 'Av.';
            app.IfProbDropDownLabel = uilabel(app.MetricInferenceTab);
            app.IfProbDropDownLabel.Text = 'If Prob';
            app.IfProbDropDown = uidropdown(app.MetricInferenceTab);
            app.IfProbDropDown.Items = {'>', '<'};
            app.IfProbDropDown.Value = '>';
            app.TextArea_5 = uitextarea(app.MetricInferenceTab);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'RCP'; 'MCDAAlgs'; 'Years'; 'Guided'; 'PrSites'; 'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CoralCover'; 'Cult_ES'; 'Prov_ES'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            % Show the figure after all components are created
            app.ADRIAOutputDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADRIA_app_example_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADRIAOutputDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADRIAOutputDisplayUIFigure)
        end
    end
end