classdef ADRIAv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SimulationPanel                 matlab.ui.container.Panel
        NumberofsimulationsEditField    matlab.ui.control.EditField
        NumberofsimulationsEditFieldLabel  matlab.ui.control.Label
        Durationinyearsfrom2026Panel    matlab.ui.container.Panel
        ShadingGauge                    matlab.ui.control.LinearGauge
        ShadingGaugeLabel               matlab.ui.control.Label
        SeedingGauge                    matlab.ui.control.LinearGauge
        SeedingGaugeLabel               matlab.ui.control.Label
        AdaptationPanel                 matlab.ui.container.Panel
        NaturalDHWyrEditField           matlab.ui.control.EditField
        NaturalDHWyrEditFieldLabel      matlab.ui.control.Label
        AssistedDHWEditField            matlab.ui.control.EditField
        AssistedDHWEditFieldLabel       matlab.ui.control.Label
        ShadinginDHWPanel               matlab.ui.container.Panel
        CoolingeffectEditField          matlab.ui.control.EditField
        CoolingeffectEditFieldLabel     matlab.ui.control.Label
        SeedinginofcoverPanel           matlab.ui.container.Panel
        OthercoralsEditField            matlab.ui.control.EditField
        OthercoralsEditFieldLabel       matlab.ui.control.Label
        AcroporaEditField               matlab.ui.control.EditField
        AcroporaEditFieldLabel          matlab.ui.control.Label
        PriorityGroupsPanel             matlab.ui.container.Panel
        SitegroupDropDown               matlab.ui.control.DropDown
        SitegroupDropDownLabel          matlab.ui.control.Label
        InterventionSelectionLabel      matlab.ui.control.Label
        InformedvsUninformedPanel       matlab.ui.container.Panel
        UnguidedCheckBox                matlab.ui.control.CheckBox
        GuidedCheckBox                  matlab.ui.control.CheckBox
        CentralPanel                    matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        Lamp                            matlab.ui.control.Lamp
        RunButton                       matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        MCDAAlgorithmButtonGroup        matlab.ui.container.ButtonGroup
        VIKORButton                     matlab.ui.control.RadioButton
        TOPSISButton                    matlab.ui.control.RadioButton
        OrderRankingButton              matlab.ui.control.RadioButton
        RisktolerancePanel              matlab.ui.container.Panel
        RiskToleranceGauge              matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_9Label  matlab.ui.control.Label
        StrongsourceshadingPanel        matlab.ui.container.Panel
        StrongSourceShadingGauge        matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_8Label  matlab.ui.control.Label
        StrongsourceseedingPanel        matlab.ui.container.Panel
        StrongSourceSeedingGauge        matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_7Label  matlab.ui.control.Label
        HighcoralcoverinterventionPanel  matlab.ui.container.Panel
        HighCoralCoverGauge             matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_6Label  matlab.ui.control.Label
        ConnectivitywhenseedingPanel    matlab.ui.container.Panel
        ConnectivitySeedingGauge        matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_5Label  matlab.ui.control.Label
        ConnectivitywhenshadingPanel    matlab.ui.container.Panel
        ConnectivityShadingGauge        matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_4Label  matlab.ui.control.Label
        AvoidHeatStressPanel            matlab.ui.container.Panel
        HeatStressGauge                 matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_3Label  matlab.ui.control.Label
        LowcoralcoverinterventionPanel  matlab.ui.container.Panel
        LowCoralCoverGauge              matlab.ui.control.LinearGauge
        LessimportantMoreimportantGauge_2Label  matlab.ui.control.Label
        AvoidWaveStressPanel            matlab.ui.container.Panel
        WaveStressGauge                 matlab.ui.control.LinearGauge
        LessimportantMoreimportantLabel  matlab.ui.control.Label
        PrioritySelectionLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             % Adjust app window size as fraction of screen size
%             units = get(groot, 'Units'); % backup Units
%             set(groot, 'Units', 'pixels');
%             scrsz = get(groot, 'ScreenSize');
%             app.UIFigure.Position = [scrsz(3)/8 scrsz(4)/8 (3/4)*scrsz(3) (3/4)*scrsz(4)];
%             set(groot, 'Units', units); % restore original units
            
            % Plot the Site domain info
            fileloc = 'Inputs/';
            load([fileloc,'MooreSitesDomainInfo.mat'])
            
            %% Plot the bathymetry over the domain
            h = pcolor(app.UIAxes,lon,lat,botz);
            set(h,'LineStyle','none')
            
            hold(app.UIAxes)
            
            % plot site locations
            text(app.UIAxes,F0(:,3),F0(:,4), cellstr(num2str(F0(:,1))), 'FontSize', 12, 'Color', 'k');
            
            colorbar(app.UIAxes)
            set(app.UIAxes,'XLim',[min(min(lon)) max(max(lon))],...
                'YLim',[min(min(lat)) max(max(lat))])
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            % Turn light green
            app.Lamp.Color = [0.14,0.68,0.14];
            
            % Disable run button to prevent accidental double-click
            set(app.RunButton, 'Enable', 'off')
            pause(0.01)
            
            currentfolder = pwd;
            addpath(genpath(currentfolder))
            
            %% Read intervention options
            % Informed vs Uninformed interventions
            if app.UnguidedCheckBox.Value == 0
                Interv.Guided = app.GuidedCheckBox.Value;
            elseif app.GuidedCheckBox.Value == 1
                Interv.Guided = [app.UnguidedCheckBox.Value,app.GuidedCheckBox.Value];
            end
            
            % MCDA algorithm choice
            if app.OrderRankingButton.Value
               alg_ind = 1;
            elseif app.TOPSISButton.Value
               alg_ind = 2;
            elseif app.VIKORButton.Value
               alg_ind = 3;
            end
            
            
            % Groups of Sites Prioritised (spatial objective)
            switch app.SitegroupDropDown.Value
                case 'All'
                    Interv.PrSites = [1,2,3];
                case '1'
                    Interv.PrSites = 1;
                case '2'
                    Interv.PrSites = 2;
                case '3'
                    Interv.PrSites = 3;
            end
            
            % Seeding (%cover)
            % Coral Group 1: Acropora - can be for different levels
            Seed1 = app.AcroporaEditField.Value;
            Interv.Seed1 = Seed1/100;
            % Coral Group 2: Other corals - can be for different levels
            Seed2 = app.OthercoralsEditField.Value;
            Interv.Seed2 = Seed2/100;
            
            % Cooling and Shading (DHW)
            Interv.SRM = str2double(app.CoolingeffectEditField.Value);
            
            % Adaptation (one or more levels)
            % Assisted (DHW)
            Interv.Aadpt = str2double(app.AssistedDHWEditField.Value);
            % Rate of Natural Adaptation (DHW/yr)
            Interv.Natad = str2double(app.NaturalDHWyrEditField.Value);
            
            % Duration
            % How many years to seed, starting in 2026
            Interv.Seedyrs = app.SeedingGauge.Value;
            % How many years to shade, starting in 2026
            Interv.Shadeyrs = app.ShadingGauge.Value;
            
            % Number of simulations
            Interv.sims = str2double(app.NumberofsimulationsEditField.Value);
            
            %% Read criteria weights
            %% Avoid Wave Stress:
            CrtWts(:,1) = app.WaveStressGauge.Value;
            % Avoid Heat Stress: 
            CrtWts(:,2) = app.HeatStressGauge.Value;
            % Account for Connectivity (Centrality) when Shading or Cooling:
            CrtWts(:,3) = app.ConnectivityShadingGauge.Value;
            % Account for Connectivity (Centrality) when Seeding:
            CrtWts(:,4) = app.ConnectivitySeedingGauge.Value;
            % Intervene where Coral Cover is High:
            CrtWts(:,5) = app.HighCoralCoverGauge.Value;
            % Intervene where Coral Cover is Low:
            CrtWts(:,6) = app.LowCoralCoverGauge.Value;
            % Seed at Strongest Sources for Priority Sites:
            CrtWts(:,7) = app.StrongSourceSeedingGauge.Value;
            % Shade at Strongest Sources for Priority Sites:
            CrtWts(:,8) = app.StrongSourceShadingGauge.Value;
            % Risk Tolerance wrt Deployed Corals:
            CrtWts(:,9) = app.RiskToleranceGauge.Value;
            
            % Launch ADRIA with the intervention and weight selected
            runADRIA(Interv,CrtWts,alg_ind)
            
            % Lamp goes back to red
            app.Lamp.Color = [0.83,0.27,0.38];
            
            % Re-enable run button
            set(app.RunButton, 'Enable', 'on')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {539, 539};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CentralPanel.Layout.Row = 2;
                app.CentralPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {271, '1x'};
                app.CentralPanel.Layout.Row = 1;
                app.CentralPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InformedvsUninformedPanel = uipanel(app.LeftPanel);
            app.InformedvsUninformedPanel.Title = 'Informed vs Uninformed';
            app.GuidedCheckBox = uicheckbox(app.InformedvsUninformedPanel);
            app.GuidedCheckBox.Text = 'Guided';
            app.GuidedCheckBox.Value = true;
            app.UnguidedCheckBox = uicheckbox(app.InformedvsUninformedPanel);
            app.UnguidedCheckBox.Text = 'Unguided';
            app.InterventionSelectionLabel = uilabel(app.LeftPanel);
            app.InterventionSelectionLabel.Text = 'Intervention Selection';
            app.PriorityGroupsPanel = uipanel(app.LeftPanel);
            app.PriorityGroupsPanel.Title = 'Priority Groups';
            app.SitegroupDropDownLabel = uilabel(app.PriorityGroupsPanel);
            app.SitegroupDropDownLabel.Text = 'Site group';
            app.SitegroupDropDown = uidropdown(app.PriorityGroupsPanel);
            app.SitegroupDropDown.Items = {'1', '2', '3', 'All'};
            app.SitegroupDropDown.Value = '3';
            app.SeedinginofcoverPanel = uipanel(app.LeftPanel);
            app.SeedinginofcoverPanel.Title = 'Seeding in % of cover';
            app.AcroporaEditFieldLabel = uilabel(app.SeedinginofcoverPanel);
            app.AcroporaEditFieldLabel.Text = 'Acropora';
            app.AcroporaEditField = uieditfield(app.SeedinginofcoverPanel, 'text');
            app.AcroporaEditField.Value = '[0,0.05,0.10]';
            app.OthercoralsEditFieldLabel = uilabel(app.SeedinginofcoverPanel);
            app.OthercoralsEditFieldLabel.Text = 'Other corals';
            app.OthercoralsEditField = uieditfield(app.SeedinginofcoverPanel, 'text');
            app.OthercoralsEditField.Value = '0';
            app.ShadinginDHWPanel = uipanel(app.LeftPanel);
            app.ShadinginDHWPanel.Title = 'Shading in DHW';
            app.CoolingeffectEditFieldLabel = uilabel(app.ShadinginDHWPanel);
            app.CoolingeffectEditFieldLabel.Text = 'Cooling effect';
            app.CoolingeffectEditField = uieditfield(app.ShadinginDHWPanel, 'text');
            app.CoolingeffectEditField.Value = '0';
            app.AdaptationPanel = uipanel(app.LeftPanel);
            app.AdaptationPanel.Title = 'Adaptation ';
            app.AssistedDHWEditFieldLabel = uilabel(app.AdaptationPanel);
            app.AssistedDHWEditFieldLabel.Text = 'Assisted (DHW)';
            app.AssistedDHWEditField = uieditfield(app.AdaptationPanel, 'text');
            app.AssistedDHWEditField.Value = '[6,12]';
            app.NaturalDHWyrEditFieldLabel = uilabel(app.AdaptationPanel);
            app.NaturalDHWyrEditFieldLabel.Text = 'Natural (DHW/yr)';
            app.NaturalDHWyrEditField = uieditfield(app.AdaptationPanel, 'text');
            app.NaturalDHWyrEditField.Value = '0.05';
            app.Durationinyearsfrom2026Panel = uipanel(app.LeftPanel);
            app.Durationinyearsfrom2026Panel.Title = 'Duration ( in # years from 2026)';
            app.SeedingGaugeLabel = uilabel(app.Durationinyearsfrom2026Panel);
            app.SeedingGaugeLabel.Text = 'Seeding';
            app.SeedingGauge = uigauge(app.Durationinyearsfrom2026Panel, 'linear');
            app.SeedingGauge.Limits = [0 25];
            app.SeedingGauge.Value = 10;
            app.ShadingGaugeLabel = uilabel(app.Durationinyearsfrom2026Panel);
            app.ShadingGaugeLabel.Text = 'Shading';
            app.ShadingGauge = uigauge(app.Durationinyearsfrom2026Panel, 'linear');
            app.ShadingGauge.Limits = [0 25];
            app.ShadingGauge.Value = 25;
            app.SimulationPanel = uipanel(app.LeftPanel);
            app.SimulationPanel.Title = 'Simulation';
            app.NumberofsimulationsEditFieldLabel = uilabel(app.SimulationPanel);
            app.NumberofsimulationsEditFieldLabel.Text = 'Number of simulations';
            app.NumberofsimulationsEditField = uieditfield(app.SimulationPanel, 'text');
            app.NumberofsimulationsEditField.Value = '50';
            app.CentralPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CentralPanel);
            title(app.UIAxes, 'Sites location')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            app.RightPanel = uipanel(app.CentralPanel);
            app.PrioritySelectionLabel = uilabel(app.RightPanel);
            app.PrioritySelectionLabel.Text = 'Priority Selection';
            app.AvoidWaveStressPanel = uipanel(app.RightPanel);
            app.AvoidWaveStressPanel.Title = 'Avoid Wave Stress';
            app.LessimportantMoreimportantLabel = uilabel(app.AvoidWaveStressPanel);
            app.LessimportantMoreimportantLabel.Text = 'Less important      More important';
            app.WaveStressGauge = uigauge(app.AvoidWaveStressPanel, 'linear');
            app.WaveStressGauge.Limits = [0 1];
            app.WaveStressGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.WaveStressGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WaveStressGauge.Value = 1;
            app.LowcoralcoverinterventionPanel = uipanel(app.RightPanel);
            app.LowcoralcoverinterventionPanel.Title = 'Low coral cover intervention';
            app.LessimportantMoreimportantGauge_2Label = uilabel(app.LowcoralcoverinterventionPanel);
            app.LessimportantMoreimportantGauge_2Label.Text = 'Less important      More important';
            app.LowCoralCoverGauge = uigauge(app.LowcoralcoverinterventionPanel, 'linear');
            app.LowCoralCoverGauge.Limits = [0 1];
            app.LowCoralCoverGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.LowCoralCoverGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AvoidHeatStressPanel = uipanel(app.RightPanel);
            app.AvoidHeatStressPanel.Title = 'Avoid Heat Stress';
            app.LessimportantMoreimportantGauge_3Label = uilabel(app.AvoidHeatStressPanel);
            app.LessimportantMoreimportantGauge_3Label.Text = 'Less important      More important';
            app.HeatStressGauge = uigauge(app.AvoidHeatStressPanel, 'linear');
            app.HeatStressGauge.Limits = [0 1];
            app.HeatStressGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.HeatStressGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HeatStressGauge.Value = 1;
            app.ConnectivitywhenshadingPanel = uipanel(app.RightPanel);
            app.ConnectivitywhenshadingPanel.Title = 'Connectivity when shading';
            app.LessimportantMoreimportantGauge_4Label = uilabel(app.ConnectivitywhenshadingPanel);
            app.LessimportantMoreimportantGauge_4Label.Text = 'Less important      More important';
            app.ConnectivityShadingGauge = uigauge(app.ConnectivitywhenshadingPanel, 'linear');
            app.ConnectivityShadingGauge.Limits = [0 1];
            app.ConnectivityShadingGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ConnectivityShadingGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ConnectivitywhenseedingPanel = uipanel(app.RightPanel);
            app.ConnectivitywhenseedingPanel.Title = 'Connectivity when seeding';
            app.LessimportantMoreimportantGauge_5Label = uilabel(app.ConnectivitywhenseedingPanel);
            app.LessimportantMoreimportantGauge_5Label.Text = 'Less important      More important';
            app.ConnectivitySeedingGauge = uigauge(app.ConnectivitywhenseedingPanel, 'linear');
            app.ConnectivitySeedingGauge.Limits = [0 1];
            app.ConnectivitySeedingGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ConnectivitySeedingGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HighcoralcoverinterventionPanel = uipanel(app.RightPanel);
            app.HighcoralcoverinterventionPanel.Title = 'High coral cover intervention';
            app.LessimportantMoreimportantGauge_6Label = uilabel(app.HighcoralcoverinterventionPanel);
            app.LessimportantMoreimportantGauge_6Label.Text = 'Less important      More important';
            app.HighCoralCoverGauge = uigauge(app.HighcoralcoverinterventionPanel, 'linear');
            app.HighCoralCoverGauge.Limits = [0 1];
            app.HighCoralCoverGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.HighCoralCoverGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.StrongsourceseedingPanel = uipanel(app.RightPanel);
            app.StrongsourceseedingPanel.Title = 'Strong source seeding';
            app.LessimportantMoreimportantGauge_7Label = uilabel(app.StrongsourceseedingPanel);
            app.LessimportantMoreimportantGauge_7Label.Text = 'Less important      More important';
            app.StrongSourceSeedingGauge = uigauge(app.StrongsourceseedingPanel, 'linear');
            app.StrongSourceSeedingGauge.Limits = [0 1];
            app.StrongSourceSeedingGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.StrongSourceSeedingGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.StrongSourceSeedingGauge.Value = 1;
            app.StrongsourceshadingPanel = uipanel(app.RightPanel);
            app.StrongsourceshadingPanel.Title = 'Strong source shading';
            app.LessimportantMoreimportantGauge_8Label = uilabel(app.StrongsourceshadingPanel);
            app.LessimportantMoreimportantGauge_8Label.Text = 'Less important      More important';
            app.StrongSourceShadingGauge = uigauge(app.StrongsourceshadingPanel, 'linear');
            app.StrongSourceShadingGauge.Limits = [0 1];
            app.StrongSourceShadingGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.StrongSourceShadingGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.RisktolerancePanel = uipanel(app.RightPanel);
            app.RisktolerancePanel.Title = 'Risk tolerance';
            app.LessimportantMoreimportantGauge_9Label = uilabel(app.RisktolerancePanel);
            app.LessimportantMoreimportantGauge_9Label.Text = 'Less important      More important';
            app.RiskToleranceGauge = uigauge(app.RisktolerancePanel, 'linear');
            app.RiskToleranceGauge.Limits = [0 1];
            app.RiskToleranceGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.RiskToleranceGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.RiskToleranceGauge.Value = 1;
            app.MCDAAlgorithmButtonGroup = uibuttongroup(app.RightPanel);
            app.MCDAAlgorithmButtonGroup.Title = 'MCDA Algorithm';
            app.OrderRankingButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.OrderRankingButton.Text = 'Order Ranking';
            app.OrderRankingButton.Value = true;
            app.TOPSISButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.TOPSISButton.Text = 'TOPSIS';
            app.VIKORButton = uiradiobutton(app.MCDAAlgorithmButtonGroup);
            app.VIKORButton.Text = 'VIKOR';
            app.RunButton = uibutton(app.CentralPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Lamp = uilamp(app.CentralPanel);
            app.Image = uiimage(app.CentralPanel);
            app.Image.ImageSource = 'ADRIAlogo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADRIAv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end